classdef animator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECODATAAnimateUIFigure          matlab.ui.Figure
        LoadsettingsButton              matlab.ui.control.Button
        OutputPanel                     matlab.ui.container.Panel
        GeneratelastframeonlyCheckBox   matlab.ui.control.CheckBox
        FilepathTextArea_output         matlab.ui.control.TextArea
        FilepathTextArea_3Label         matlab.ui.control.Label
        SetoutputdirectoryButton        matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AnimaltrackdataTab              matlab.ui.container.Tab
        DatafiltersPanel                matlab.ui.container.Panel
        NumberofpointsinselectionTextArea  matlab.ui.control.TextArea
        NumberofpointsinselectionTextAreaLabel  matlab.ui.control.Label
        LatesttimestampinselectionTextArea  matlab.ui.control.TextArea
        LatesttimestampinselectionTextAreaLabel  matlab.ui.control.Label
        EarliesttimestampinselectionTextArea  matlab.ui.control.TextArea
        EarliesttimestampinselectionTextAreaLabel  matlab.ui.control.Label
        NumberofindividualsselectedTextArea  matlab.ui.control.TextArea
        NumberofindividualsselectedTextAreaLabel  matlab.ui.control.Label
        MaxlongitudeEditField           matlab.ui.control.NumericEditField
        MaxlongitudeEditFieldLabel      matlab.ui.control.Label
        MinlongitudeEditField           matlab.ui.control.NumericEditField
        MinlongitudeEditFieldLabel      matlab.ui.control.Label
        MaxlatitudeEditField            matlab.ui.control.NumericEditField
        MaxlatitudeEditFieldLabel       matlab.ui.control.Label
        MinlatitudeEditField            matlab.ui.control.NumericEditField
        MinlatitudeEditFieldLabel       matlab.ui.control.Label
        EnddateDatePicker               matlab.ui.control.DatePicker
        EnddateDatePickerLabel          matlab.ui.control.Label
        StartdateDatePicker             matlab.ui.control.DatePicker
        StartdateDatePickerLabel        matlab.ui.control.Label
        SelectionsummaryLabel           matlab.ui.control.Label
        UpdatefiltersButton             matlab.ui.control.Button
        GeographicextentLabel           matlab.ui.control.Label
        TimerangeLabel                  matlab.ui.control.Label
        IndividualsLabel                matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        AllindividualsNode              matlab.ui.container.TreeNode
        FilepathTextArea_tracks         matlab.ui.control.TextArea
        FilepathTextAreaLabel           matlab.ui.control.Label
        SelecttrackdataButton           matlab.ui.control.Button
        TracksvisualizationoptionsTab_main  matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        TracksvisualizationoptionsTab   matlab.ui.container.Tab
        VisualizationoptionsLabel       matlab.ui.control.Label
        UITable_colors                  matlab.ui.control.Table
        UsecustomcolorsCheckBox         matlab.ui.control.CheckBox
        UseblackmarkersCheckBox         matlab.ui.control.CheckBox
        ShowlegendCheckBox              matlab.ui.control.CheckBox
        ClearcolorsButton               matlab.ui.control.Button
        MarkersizeSpinner_tracks        matlab.ui.control.Spinner
        MarkersizeSpinner_2Label_3      matlab.ui.control.Label
        TrackopacitySpinner             matlab.ui.control.Spinner
        TrackopacitySpinner_3Label      matlab.ui.control.Label
        TrackfrequencyhoursSpinner      matlab.ui.control.Spinner
        TrackfrequencyhoursSpinner_3Label  matlab.ui.control.Label
        ColortracksbyDropDown           matlab.ui.control.DropDown
        ColortracksbyDropDown_3Label    matlab.ui.control.Label
        TracklinewidthSpinner           matlab.ui.control.Spinner
        TracklinewidthSpinner_3Label    matlab.ui.control.Label
        MarkerstyleDropDown             matlab.ui.control.DropDown
        MarkerstyleDropDown_4Label      matlab.ui.control.Label
        TrackmemoryEditField            matlab.ui.control.NumericEditField
        TrackmemoryEditField_3Label     matlab.ui.control.Label
        FadetracksCheckBox              matlab.ui.control.CheckBox
        AddcolorButton                  matlab.ui.control.Button
        PresencevisualizationoptionsTab  matlab.ui.container.Tab
        UsepresencevisualizationoptionsCheckBox  matlab.ui.control.CheckBox
        SizeparameterDropDown           matlab.ui.control.DropDown
        SizeparameterDropDownLabel      matlab.ui.control.Label
        PresencevisualizationoptionsLabel  matlab.ui.control.Label
        UITable_colors_3                matlab.ui.control.Table
        UsecustomcolorsCheckBox_3       matlab.ui.control.CheckBox
        UseblackmarkersonlyCheckBox     matlab.ui.control.CheckBox
        ShowlegendCheckBox_3            matlab.ui.control.CheckBox
        ClearcolorsButton_3             matlab.ui.control.Button
        LocationopacitySpinner          matlab.ui.control.Spinner
        LocationopacitySpinnerLabel     matlab.ui.control.Label
        VisualiztionfrequencyhoursSpinner  matlab.ui.control.Spinner
        VisualiztionfrequencyhoursSpinnerLabel  matlab.ui.control.Label
        ColorparameterDropDown          matlab.ui.control.DropDown
        ColorparameterDropDownLabel     matlab.ui.control.Label
        MarkerstyleDropDown_3           matlab.ui.control.DropDown
        MarkerstyleDropDown_3Label      matlab.ui.control.Label
        LocationmemoryhoursEditField    matlab.ui.control.NumericEditField
        LocationmemoryhoursEditFieldLabel  matlab.ui.control.Label
        AddcolorButton_3                matlab.ui.control.Button
        EnvironmentaldataTab            matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        GriddedcolormapTab              matlab.ui.container.Tab
        GriddeddataLabel                matlab.ui.control.Label
        ColoroptionsLabel               matlab.ui.control.Label
        DatasetdimensionsLabel          matlab.ui.control.Label
        ColorbarmaxEditField            matlab.ui.control.NumericEditField
        ColorbarmaxEditFieldLabel       matlab.ui.control.Label
        VariabletoanimateDropDown_gridded  matlab.ui.control.DropDown
        VariabletoanimateDropDownLabel  matlab.ui.control.Label
        ColorbarminEditField            matlab.ui.control.NumericEditField
        ColorbarminEditFieldLabel       matlab.ui.control.Label
        FilepathTextArea_gridded        matlab.ui.control.TextArea
        FilepathTextArea_2Label         matlab.ui.control.Label
        LongitudevariableDropDownLabel  matlab.ui.control.Label
        LongitudevariableDropDown_gridded  matlab.ui.control.DropDown
        LatitudevariableLabel           matlab.ui.control.Label
        LatitudevariableDropDown_gridded  matlab.ui.control.DropDown
        TimevariableLabel               matlab.ui.control.Label
        TimevariableDropDown_gridded    matlab.ui.control.DropDown
        ColormapDropDown_gridded        matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        AutomaticcolorbarlimitsCheckBox  matlab.ui.control.CheckBox
        ShowcolorbarCheckBox            matlab.ui.control.CheckBox
        CleardataButton_gridded         matlab.ui.control.Button
        InvertcolormapCheckBox          matlab.ui.control.CheckBox
        SelectgriddeddataButton         matlab.ui.control.Button
        ContoursTab                     matlab.ui.container.Tab
        ContouroptionsLabel             matlab.ui.control.Label
        DatasetdimensionsLabel_2        matlab.ui.control.Label
        ContourlinewidthSpinner         matlab.ui.control.Spinner
        ContourlinewidthSpinnerLabel    matlab.ui.control.Label
        ContourlinecolorDropDown        matlab.ui.control.DropDown
        ContourlinecolorDropDownLabel   matlab.ui.control.Label
        FilepathTextArea_2Label_6       matlab.ui.control.Label
        CleardataButton_contour         matlab.ui.control.Button
        LongitudevariableDropDownLabel_2  matlab.ui.control.Label
        LatitudevariableLabel_2         matlab.ui.control.Label
        TimevariableLabel_2             matlab.ui.control.Label
        VariabletoanimateDropDownLabel_2  matlab.ui.control.Label
        ShowcontourtextCheckBox         matlab.ui.control.CheckBox
        LongitudevariableDropDown_contour  matlab.ui.control.DropDown
        LatitudevariableDropDown_contour  matlab.ui.control.DropDown
        TimevariableDropDown_contour    matlab.ui.control.DropDown
        VariabletoanimateDropDown_contour  matlab.ui.control.DropDown
        FilepathTextArea_contour        matlab.ui.control.TextArea
        SelectcontourdataButton         matlab.ui.control.Button
        ContourdataLabel                matlab.ui.control.Label
        VectorfieldsTab                 matlab.ui.container.Tab
        AutoscaleCheckBox               matlab.ui.control.CheckBox
        QuiverspeedSpinner              matlab.ui.control.Spinner
        QuiverspeedSpinnerLabel         matlab.ui.control.Label
        QuiversizeSpinner               matlab.ui.control.Spinner
        ContourlinewidthSpinner_2Label_2  matlab.ui.control.Label
        QuivercolorDropDown             matlab.ui.control.DropDown
        ContourlinecolorDropDown_2Label_2  matlab.ui.control.Label
        VectoroptionsLabel              matlab.ui.control.Label
        DatasetdimensionsLabel_3        matlab.ui.control.Label
        U_vector_DropDown               matlab.ui.control.DropDown
        UvectorLabel                    matlab.ui.control.Label
        LongitudevariableDropDown_vectorfield  matlab.ui.control.DropDown
        LongitudevariableDropDownLabel_3  matlab.ui.control.Label
        LatitudevariableDropDown_vectorfield  matlab.ui.control.DropDown
        LatitudevariableLabel_3         matlab.ui.control.Label
        TimevariableDropDown_vectorfield  matlab.ui.control.DropDown
        TimevariableLabel_3             matlab.ui.control.Label
        FilepathTextArea_vectorfield    matlab.ui.control.TextArea
        FilepathTextArea_2Label_7       matlab.ui.control.Label
        V_vector_DropDown               matlab.ui.control.DropDown
        VvectorLabel                    matlab.ui.control.Label
        VectorfielddatawindcurrentsLabel  matlab.ui.control.Label
        CleardataButton_vectorfield     matlab.ui.control.Button
        SelectvectorfielddataButton     matlab.ui.control.Button
        StaticNetCDFTab                 matlab.ui.container.Tab
        StaticVarDropDown               matlab.ui.control.DropDown
        VariabletoanimateDropDownLabel_3  matlab.ui.control.Label
        TimeSliceDropDown               matlab.ui.control.DropDown
        TimevariableLabel_6             matlab.ui.control.Label
        TimevariableLabel_5             matlab.ui.control.Label
        StaticTimeDropDown              matlab.ui.control.DropDown
        LongitudevariableDropDownLabel_5  matlab.ui.control.Label
        StaticLonVarDropDown            matlab.ui.control.DropDown
        LatitudevariableLabel_5         matlab.ui.control.Label
        StaticLatVarDropDown            matlab.ui.control.DropDown
        GriddeddataLabel_2              matlab.ui.control.Label
        ColoroptionsLabel_2             matlab.ui.control.Label
        DatasetStaticdimensionsLabel_4  matlab.ui.control.Label
        StaticCLimMaxField              matlab.ui.control.NumericEditField
        ColorbarmaxEditField_2Label     matlab.ui.control.Label
        StaticCLimMinField              matlab.ui.control.NumericEditField
        ColorbarminEditField_2Label     matlab.ui.control.Label
        StaticNcDataPathTextArea        matlab.ui.control.TextArea
        FilepathTextArea_2Label_8       matlab.ui.control.Label
        StaticCmapDropDown              matlab.ui.control.DropDown
        ColormapDropDownLabel_2         matlab.ui.control.Label
        AutomaticcolorbarlimitsStaticNcCheckBox  matlab.ui.control.CheckBox
        StaticShowColorbarCheckBox      matlab.ui.control.CheckBox
        ClearStaticDataButton           matlab.ui.control.Button
        StaticInvertCheckBox            matlab.ui.control.CheckBox
        SelectstaticdataButton          matlab.ui.control.Button
        StaticGeoTIFFTab                matlab.ui.container.Tab
        GriddeddataLabel_3              matlab.ui.control.Label
        ColoroptionsLabel_3             matlab.ui.control.Label
        DatasetdimensionsLabel_5        matlab.ui.control.Label
        ColorbarmaxEditField_3          matlab.ui.control.NumericEditField
        ColorbarmaxEditField_3Label     matlab.ui.control.Label
        ColorbarminEditField_3          matlab.ui.control.NumericEditField
        ColorbarminEditField_3Label     matlab.ui.control.Label
        FilepathTextArea_gridded_3      matlab.ui.control.TextArea
        FilepathTextArea_2Label_9       matlab.ui.control.Label
        ColormapDropDown_gridded_3      matlab.ui.control.DropDown
        ColormapDropDownLabel_3         matlab.ui.control.Label
        AutomaticcolorbarlimitsCheckBox_3  matlab.ui.control.CheckBox
        ShowcolorbarCheckBox_3          matlab.ui.control.CheckBox
        CleardataButton_gridded_3       matlab.ui.control.Button
        InvertcolormapCheckBox_3        matlab.ui.control.CheckBox
        SelectgriddeddataButton_3       matlab.ui.control.Button
        ShapefilesTab                   matlab.ui.container.Tab
        MarkersizeSpinner_shapefile     matlab.ui.control.Spinner
        MarkersizeSpinnerLabel_2        matlab.ui.control.Label
        MarkerstyleDropDown_shapefile   matlab.ui.control.DropDown
        MarkerstyleDropDown_2Label      matlab.ui.control.Label
        PointcolorDropDown_shapefile    matlab.ui.control.DropDown
        PointcolorDropDownLabel         matlab.ui.control.Label
        PolygonoptionsLabel             matlab.ui.control.Label
        PointoptionsLabel               matlab.ui.control.Label
        LineoptionsLabel                matlab.ui.control.Label
        ClearlastshapefileButton        matlab.ui.control.Button
        ClearallshapefilesButton        matlab.ui.control.Button
        UITable_shapefiles              matlab.ui.control.Table
        FaceopacitySpinner              matlab.ui.control.Spinner
        FaceopacitySpinnerLabel         matlab.ui.control.Label
        EdgecolorDropDown               matlab.ui.control.DropDown
        EdgecolorDropDownLabel          matlab.ui.control.Label
        FacecolorDropDown               matlab.ui.control.DropDown
        FacecolorDropDownLabel          matlab.ui.control.Label
        LinewidthSpinner                matlab.ui.control.Spinner
        LinewidthSpinnerLabel           matlab.ui.control.Label
        LinecolorDropDown               matlab.ui.control.DropDown
        LinecolorDropDownLabel          matlab.ui.control.Label
        FilepathTextArea_2Label_2       matlab.ui.control.Label
        FilepathTextArea_shapefile      matlab.ui.control.TextArea
        SelectshapefileButton           matlab.ui.control.Button
        LabeledpointsTab                matlab.ui.container.Tab
        UITable_colors_labeledPoints    matlab.ui.control.Table
        ClearcolorsButton_labeledPoints  matlab.ui.control.Button
        AddcolorButton_labeledPoints    matlab.ui.control.Button
        UsecustomcolorsCheckBox_labeledPoints  matlab.ui.control.CheckBox
        ColorpointsbyDropDown           matlab.ui.control.DropDown
        ColorpointsbyDropDownLabel      matlab.ui.control.Label
        ColorpointsbyattributeCheckBox  matlab.ui.control.CheckBox
        LabelsizeSpinner_labeledPoints  matlab.ui.control.Spinner
        LabelsizeSpinnerLabel           matlab.ui.control.Label
        LabelcolorDropDown_labeledPoints  matlab.ui.control.DropDown
        LabelcolorDropDownLabel         matlab.ui.control.Label
        ShowlabeltextCheckBox           matlab.ui.control.CheckBox
        CleardataButton_labeledPoints   matlab.ui.control.Button
        MarkersizeSpinner_labeledPoints  matlab.ui.control.Spinner
        MarkersizeSpinnerLabel          matlab.ui.control.Label
        MarkercolorDropDown_labeledPoints  matlab.ui.control.DropDown
        MarkercolorDropDownLabel        matlab.ui.control.Label
        FilepathTextAreaLabel_labeledPoints  matlab.ui.control.Label
        FilepathTextArea_labeledPoints  matlab.ui.control.TextArea
        SelectfileButton_labeledPoints  matlab.ui.control.Button
        Labeledpoints                   matlab.ui.control.Label
        ElevationTab                    matlab.ui.container.Tab
        ContourlinecolorDropDown_elevation  matlab.ui.control.DropDown
        ContourlinecolorDropDown_2Label  matlab.ui.control.Label
        NumberoflevelsSpinner           matlab.ui.control.Spinner
        NumberoflevelsSpinnerLabel      matlab.ui.control.Label
        IncludeelevationcontoursCheckBox  matlab.ui.control.CheckBox
        CheckBox                        matlab.ui.control.CheckBox
        ContourlinewidthSpinner_elevation  matlab.ui.control.Spinner
        ContourlinewidthSpinner_2Label  matlab.ui.control.Label
        ShowcontourtextCheckBox_elevation  matlab.ui.control.CheckBox
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        CreateanimationButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        gridded_data
        contour_data
        quiver_data
        elevation
        shapefile_stack
        raster_image
        labeled_points
        labeled_points_colors
        start_time
        end_time
        raw_track_data % input dataset
        tracks
        output_dir
        selected_individuals
        track_colors
         % Static NetCDF state / UI mirrors
        StaticNcFile                char   = ''
        StaticLatVar               (1,:) char = ''
        StaticLonVar               (1,:) char = ''
        StaticVar                  (1,:) char = ''
        StaticShowColorbar         logical = true
        StaticInvertCmap           logical = false
        StaticCLim                 double  = []      % [min max] or []
        StaticCmapName             char    = 'parula' % name from dropdown
        StaticNcVars               cell    = {}      % list of variables from ncinfo
        StaticNcCoordGuess         struct  = struct('lat','','lon','') % autodetected names
        StaticNcReady              logical = false;
        % Presence App
        presence_colors
    end
    
    methods (Access = private)
        %%
        function populateStaticNcDropdowns(app, ncfile)
            % Fill Static NetCDF tab dropdowns based on a selected NetCDF file.
            info  = ncinfo(ncfile);
            vinfo = info.Variables;
            vnames = string({vinfo.Name});
        
            % 1) Variables list (for field)
            app.StaticVarDropDown.Items = cellstr(vnames);
        
            % Prefer the first 2-D non-coordinate variable as default
            defaultVar = app.pickFirst2DVar(vinfo);
        
            if isempty(defaultVar)
                % Fallback: take first non-(lat/lon/time) variable, if any
                maskCoord = contains(lower(vnames), ["lat","lon","time","latitude","longitude"]);
                candidates = vnames(~maskCoord);
                if ~isempty(candidates)
                    defaultVar = candidates(1);
                else
                    % Last resort: just take the first variable
                    defaultVar = vnames(1);
                end
            end
        
            app.StaticVarDropDown.Value = char(defaultVar);
        
            % 2) Guess lat / lon variables
            latCandidates = vnames(contains(lower(vnames), ["lat","latitude"]));
            lonCandidates = vnames(contains(lower(vnames), ["lon","longitude"]));
        
            if isempty(latCandidates)
                latDefault = vnames(1);
            else
                latDefault = latCandidates(1);
            end
        
            if isempty(lonCandidates)
                lonDefault = vnames(2);
            else
                lonDefault = lonCandidates(1);
            end
        
            app.StaticLatVarDropDown.Items = cellstr(vnames);
            app.StaticLatVarDropDown.Value = char(latDefault);
        
            app.StaticLonVarDropDown.Items = cellstr(vnames);
            app.StaticLonVarDropDown.Value = char(lonDefault);

        
            % 3) Time variable candidates
            allLower = lower(vnames);
            isTime   = contains(allLower, 'time');
            timeVars = vnames(isTime);
        
            if isempty(timeVars)
                % No time dimension 
                app.StaticTimeDropDown.Items  = {'No time variable'};
                app.StaticTimeDropDown.Value  = 'No time variable';
                app.StaticTimeDropDown.Enable = 'off';
        
                app.TimeSliceDropDown.Items   = {};
                app.TimeSliceDropDown.Value   = {};
                app.TimeSliceDropDown.Enable  = 'off';
            else
                % User can choose to use time or ignore it
                app.StaticTimeDropDown.Items  = [{'No time variable'}; cellstr(timeVars(:))];
                app.StaticTimeDropDown.Value  = 'No time variable';
                app.StaticTimeDropDown.Enable = 'on';
        
                app.TimeSliceDropDown.Items   = {};
                app.TimeSliceDropDown.Value   = {};
                app.TimeSliceDropDown.Enable  = 'off';
            end
        end

    %%
        
        function name = firstMatch(app, list, candidates)
            name = '';
            for c = candidates
                hit = list(strcmpi(list, c));
                if ~isempty(hit), name = hit{1}; return; end
            end
        end
        
        function varname = pickFirst2DVar(app, vars)
            varname = '';
            for k=1:numel(vars)
                dims = vars(k).Size;
                if numel(dims)==2
                    nm = vars(k).Name;
                    if ~contains(lower(nm),'lat') && ~contains(lower(nm),'lon')
                        varname = nm; return;
                    end
                end
            end
        end


        %%
        function loadStaticLayerIntoApp(app)
            % Load or reload the static NetCDF layer into app.gridded_data
            % depending on StaticTimeDropDown / TimeSliceDropDown.

            if isempty(app.StaticNcFile)
                return;
            end
        
            fname    = app.StaticNcFile;
            latvar   = char(app.StaticLatVarDropDown.Value);
            lonvar   = char(app.StaticLonVarDropDown.Value);
            varname  = char(app.StaticVarDropDown.Value);
            cmapName = char(app.StaticCmapDropDown.Value);
        
            invertFlag = logical(app.StaticInvertCheckBox.Value);
            showCbar   = logical(app.StaticShowColorbarCheckBox.Value);
        
            % Colorbar limits
            if app.AutomaticcolorbarlimitsStaticNcCheckBox.Value
                clim = [];
            else
                lo = app.StaticCLimMinField.Value;
                hi = app.StaticCLimMaxField.Value;
                if isnan(lo) || isnan(hi) || hi <= lo
                    clim = [];
                else
                    clim = [lo hi];
                end
            end
        
            % Time choice
            timeChoice = '';
            if ~isempty(app.StaticTimeDropDown.Items)
                timeChoice = char(app.StaticTimeDropDown.Value);
            end
        
            %  Mode A: no time variable (pure 2-D)
            if isempty(timeChoice) || strcmp(timeChoice, 'No time variable')
                gd = StaticGriddedData(fname, ...
                    latvar = latvar, ...
                    lonvar = lonvar, ...
                    var_of_interest = varname, ...
                    cmap = cmapName, ...
                    invert_cmap = invertFlag, ...
                    cbar_limits = clim, ...
                    show_colorbar = showCbar);
        
                gd.load();  % uses read_nc_2d under the hood
        
                app.gridded_data = gd;
                app.StatusTextArea.Value = sprintf("Static layer ready (2-D): %s | var=%s", ...
                                                   fname, varname);
                return;
            end
        
            %  Mode B: static time slice from 3-D variable 
            timevar = timeChoice;
        
            % Determine time index from TimeSliceDropDown
            labels = app.TimeSliceDropDown.Items;
            if isempty(labels)
                timeIndex = 1;
            else
                currentLabel = char(app.TimeSliceDropDown.Value);
                tok = regexp(currentLabel, '^\s*(\d+)\s*:', 'tokens', 'once');
                if ~isempty(tok)
                    timeIndex = str2double(tok{1});
                else
                    % fallback: position in the list
                    ksel = find(strcmp(labels, currentLabel), 1);
                    if isempty(ksel)
                        timeIndex = 1;
                    else
                        timeIndex = ksel;
                    end
                end
            end
        
            % Read one time slice using existing helper (assumed to be in project)
            [lat, lon, ~, slice] = unpack_netcdf( ...
                fname, ...
                latvar, ...
                lonvar, ...
                timevar, ...
                varname, ...
                start = timeIndex, ...
                count = 1);
        
            % slice may be [lat x lon x 1], [lon x lat x 1], [1 x lat x lon] etc.
            slice2 = squeeze(slice);
            sz2    = size(slice2);
        
            nlat = numel(lat);
            nlon = numel(lon);
        
            if numel(sz2) ~= 2
                error('StaticNetCDF:Not2D', ...
                    'Time slice of "%s" is not 2-D after squeeze (ndims=%d).', ...
                    varname, numel(sz2));
            end
        
            if sz2(1) == nlat && sz2(2) == nlon
                field2d = slice2;         % [lat x lon]
            elseif sz2(1) == nlon && sz2(2) == nlat
                field2d = permute(slice2, [2 1]); % [lon x lat] -> [lat x lon]
            else
                error('StaticNetCDF:SizeMismatch', ...
                    'Time slice: size=[%d %d], numel(lat)=%d, numel(lon)=%d', ...
                    sz2(1), sz2(2), nlat, nlon);
            end
        
            % Flip lat if descending
            if nlat > 1 && lat(1) > lat(end)
                lat     = flipud(lat);
                field2d = flipud(field2d);
            end
        
            % Create static gridded data and inject preloaded field
            gd = StaticGriddedData(fname, ...
                latvar = latvar, ...
                lonvar = lonvar, ...
                var_of_interest = varname, ...
                cmap = cmapName, ...
                invert_cmap = invertFlag, ...
                cbar_limits = clim, ...
                show_colorbar = showCbar);
        
            gd.lat       = lat;
            gd.lon       = lon;
            gd.field2d   = field2d;
            gd.is_loaded = true;
        
            app.gridded_data = gd;
            app.StatusTextArea.Value = sprintf("Static layer ready (time slice %d): %s | var=%s", ...
                                               timeIndex, fname, varname);
        end

        %%
        function update_status_text(app, status_text)
            try
                app.StatusTextArea.Value = status_text;
                drawnow;
            catch
            end
        end

        function save_settings(app)
            try
                props = properties(app);
                values = cell(1, length(props));
                data = cell(1, length(props));
                items = cell(1, length(props));
                visibilities = cell(1, length(props));
                
                for i = 1:length(props)
                    propName = props{i};
                    property = app.(propName);
                    if isprop(property, 'Value')
                        values{i} = app.(propName).Value;
                    end
                    if isprop(property, 'Data')
                        data{i} = app.(propName).Data;
                    end
                    if isprop(property, 'Items')
                        items{i} = app.(propName).Items;
                    end
                    if isprop(property, 'Visible')
                        visibilities{i} = app.(props{i}).Visible;
                    end
                end


                settings_fname = 'settings.mat';
                vars_to_save =  ["gridded_data" "contour_data" "quiver_data" "elevation" ...
                     "shapefile_stack" "raster_image" "labeled_points" ... 
                     "start_time" "end_time" ...
                     "raw_track_data" "tracks" "output_dir" ...
                     "selected_individuals" "track_colors" "presence_colors" "labeled_points_colors"];

                if isfolder(app.output_dir)
                    save_path = fullfile(app.output_dir, settings_fname);
                    
                    var_map = containers.Map;
                    for i=1:length(vars_to_save)
                        var_map(vars_to_save(i)) = app.(vars_to_save(i));
                    end

                    save(save_path, "props", "values", "data", "items", "var_map", "visibilities", "var_map")

                else
                    app.update_status_text("Please select a valid output directory");
                end
            catch e
                app.update_status_text(e.getReport);
            end
        end
        
                function numeric_vars = get_numeric_track_variables(app)
            numeric_vars = {};
            try
                if isempty(app.raw_track_data)
                    return;
                end

                varnames = app.raw_track_data.Properties.VariableNames;

                for k = 1:numel(varnames)
                    v = app.raw_track_data.(varnames{k});

                    if isnumeric(v) || islogical(v)
                        numeric_vars{end+1} = varnames{k}; %#ok<AGROW>
                    end
                end
            catch e
                app.update_status_text(e.getReport);
            end
        end


        function update_widgets_from_track_data(app)
            try
                varnames = app.raw_track_data.Properties.VariableNames;
                numeric_vars = app.get_numeric_track_variables();

                % ----------------------------
                % Tree of individuals
                % ----------------------------
                if ismember('individual_local_identifier', varnames)
                    individuals = unique(app.raw_track_data.individual_local_identifier);

                    for i = 1:length(individuals)
                        uitreenode(app.AllindividualsNode, "Text", individuals{i});
                    end
                    expand(app.Tree);
                end

                % ----------------------------
                % Tracks visualization options
                % ----------------------------
                app.ColortracksbyDropDown.Items = varnames;

                if ismember('individual_local_identifier', varnames)
                    app.ColortracksbyDropDown.Value = 'individual_local_identifier';
                elseif ~isempty(varnames)
                    app.ColortracksbyDropDown.Value = varnames{1};
                end

                % ----------------------------
                % Presence visualization options
                % ----------------------------

                % Size parameter -> numeric only
                if ~isempty(numeric_vars)
                    app.SizeparameterDropDown.Items = numeric_vars;

                    if ismember('count', numeric_vars)
                        app.SizeparameterDropDown.Value = 'count';
                    else
                        app.SizeparameterDropDown.Value = numeric_vars{1};
                    end
                else
                    app.SizeparameterDropDown.Items = {};
                    app.SizeparameterDropDown.Value = {};
                end

                % Color parameter -> all variables
                app.ColorparameterDropDown.Items = varnames;

                if ismember('species', varnames)
                    app.ColorparameterDropDown.Value = 'species';
                elseif ismember('individual_local_identifier', varnames)
                    app.ColorparameterDropDown.Value = 'individual_local_identifier';
                elseif ~isempty(varnames)
                    app.ColorparameterDropDown.Value = varnames{1};
                else
                    app.ColorparameterDropDown.Value = {};
                end

            catch e
                app.update_status_text(e.getReport);
            end
        end
        

        function apply_track_filters(app)
            try
                % Reset the tracks data to the raw version to get rid of
                % previous filters
                app.tracks.data = app.raw_track_data;

                % Select time window 
                app.tracks.select_timerange(app.start_time, app.end_time);
    
                % Select extent 
                app.tracks.select_bbox(app.MinlatitudeEditField.Value, app.MaxlatitudeEditField.Value, ... 
                    app.MinlongitudeEditField.Value, app.MaxlongitudeEditField.Value);

                % Select individuals 
                app.tracks.select_individuals(app.selected_individuals);
            catch e
                app.update_status_text(e.getReport);
            end
            
        end
        
        
        function autoselect_valid_varnames(app, dataset, time_dropdown, lat_dropdown, lon_dropdown, var_dropdown)
            try
                potential_timevars = {'time', 'timestamp'};
                potential_latvars = {'lat', 'latitude'};
                potential_lonvars = {'lon', 'long', 'longitude'};
    
                matched_vars = {};
                
                if isa(dataset, 'GriddedData')
                    data_varnames = dataset.varnames;
                elseif isa(dataset, 'Quivers')
                    data_varnames = dataset.varnames;
                else
                    data_varnames = dataset("varnames");
                end
    
                matched_timevar = intersect(data_varnames, potential_timevars);
                if length(matched_timevar) >= 1
                    app.(time_dropdown).Value = matched_timevar{1};
                    matched_vars(1+length(matched_vars)) = matched_timevar(1);
                end
    
                matched_latvar = intersect(data_varnames, potential_latvars);
                if length(matched_latvar) >= 1
                    app.(lat_dropdown).Value = matched_latvar{1};
                    matched_vars(1+length(matched_vars)) = matched_latvar(1);
    
                end
    
                matched_lonvar = intersect(data_varnames, potential_lonvars);
                if length(matched_lonvar) >= 1
                    app.(lon_dropdown).Value = matched_lonvar{1};
                    matched_vars(1+length(matched_vars)) = matched_lonvar(1);
    
                end
    
                % Update the var of interest to the 1st var not matching time,
                % lat, or lon
                other_vars = data_varnames(~ismember(data_varnames,matched_vars));
                if length(matched_timevar) >= 1
                    if isstruct(var_dropdown)
                        app.(getfield(var_dropdown, 'u')).Value = other_vars{1};
                        app.(getfield(var_dropdown, 'v')).Value = other_vars{2};

                    else   
                        app.(var_dropdown).Value= other_vars{1};
                    end
                end
            catch e
                app.update_status_text(e.getReport);
            end                
        
        end

        function initStaticNcTab(app)
            % Initialize the Static NetCDF tab default state

            % Colormap list MUST match Gridded Colormap tab logic
            app.StaticCmapDropDown.Items = {'green','blue','diverging','jet'};
            app.StaticCmapDropDown.Value = 'green';
        
            % Variable / coord dropdowns start empty
            app.StaticVarDropDown.Items      = {};
            app.StaticVarDropDown.Value      = {};
            app.StaticLatVarDropDown.Items   = {};
            app.StaticLatVarDropDown.Value   = {};
            app.StaticLonVarDropDown.Items   = {};
            app.StaticLonVarDropDown.Value   = {};
        
            % Time controls: initially no time variable
            app.StaticTimeDropDown.Items  = {'No time variable'};
            app.StaticTimeDropDown.Value  = 'No time variable';
            app.StaticTimeDropDown.Enable = 'off';
        
            app.TimeSliceDropDown.Items   = {};
            app.TimeSliceDropDown.Value   = {};
            app.TimeSliceDropDown.Enable  = 'off';
        
            % Color options defaults for static layer
            app.StaticInvertCheckBox.Value      = false;
            app.StaticShowColorbarCheckBox.Value = true;
            app.AutomaticcolorbarlimitsStaticNcCheckBox.Value = true;
        end
        
        function clim = collectCLim(app)
            lo = app.StaticCLimMinField.Value;
            hi = app.StaticCLimMaxField.Value;
            if isnan(lo) || isnan(hi) || hi<=lo
                clim = []; % auto
            else
                clim = [lo hi];
            end
        end
        
        function BrowseStaticGeoTIFFButtonPushed(app,event)
            try
                % select GeoTIFF
                [fname, fpath] = uigetfile({'*.tif;*.tiff', 'GeoTIFF (*.tif, *.tiff)'}, ...
                                           'Select static GeoTIFF');
                if isequal(fname, 0)
                    return;
                end

                fullName = fullfile(fpath, fname);
                app.FilepathTextArea_gridded_3.Value = fullName;

                % Update the size caption (if possible)
                try
                    info = georasterinfo(fullName);
                    sz   = info.RasterSize;
                    app.DatasetdimensionsLabel_5.Text = sprintf('Dataset dimensions: %d x %d', sz(1), sz(2));
                catch
                    app.DatasetdimensionsLabel_5.Text = 'Dataset dimensions: (unknown)';
                end

                % Initialize Map with raster parameters
                app.raster_image = containers.Map();
                app.raster_image('filename') = fullName;

                % Colorbar limits
                if ~logical(app.AutomaticcolorbarlimitsCheckBox_3.Value)
                    lo = app.ColorbarminEditField_3.Value;
                    hi = app.ColorbarmaxEditField_3.Value;
                    app.raster_image('cbar_limits') = [lo hi];
                else
                    app.raster_image('cbar_limits') = [];
                end

                % Invert + show colorbar
                app.raster_image('invert_cmap')   = logical(app.InvertcolormapCheckBox_3.Value);
                app.raster_image('show_colorbar') = logical(app.ShowcolorbarCheckBox_3.Value);

                app.update_status_text("Static GeoTIFF layer ready.");
            catch e
                app.update_status_text(e.getReport);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                % Initialize inputs for the animation function 
                app.gridded_data = {};
                app.contour_data = containers.Map();
                app.quiver_data = {};
                app.shapefile_stack = {};
                app.raster_image = containers.Map();
                app.labeled_points = containers.Map();
                app.labeled_points_colors = [];
                app.elevation = containers.Map();
                app.track_colors = [];
                app.presence_colors = [];
                appRoot = fileparts(fileparts(mfilename('fullpath')));   % .../ecodata-matlab-v3
                addpath(genpath(fullfile(appRoot, 'functions')));
                addpath(genpath(fullfile(appRoot, 'm_map')));
                initStaticNcTab(app);
            catch e
                app.update_status_text(e.getReport);
            end

        end

        % Button pushed function: SelecttrackdataButton
        function SelecttrackdataButtonPushed(app, event)
            try

                % Make sure individual nodes are cleared
                individual_nodes = app.AllindividualsNode.Children;
                individual_nodes.delete;

                % Open file browser to select input data 
                [input_datafile, input_path] = uigetfile('*.csv', 'select');
                input_fullpath = fullfile(input_path, input_datafile);
                app.FilepathTextArea_tracks.Value = input_fullpath;
                
                %load the data
                app.update_status_text("Loading data...");
                app.tracks = Tracks(input_fullpath);
                app.update_status_text("Track data loaded.");

                app.raw_track_data = app.tracks.data;
    
                %update the geolimits 
                [latlim, lonlim] = get_geolimits(app.raw_track_data, .10);
    
                app.MinlatitudeEditField.Value = latlim(1);
                app.MaxlatitudeEditField.Value = latlim(2);
                app.MinlongitudeEditField.Value = lonlim(1);
                app.MaxlongitudeEditField.Value = lonlim(2);
    
                % set default start and end times 
                app.start_time = min(app.raw_track_data.timestamp);
                app.end_time = max(app.raw_track_data.timestamp);
                app.StartdateDatePicker.Value = app.start_time;
                app.EnddateDatePicker.Value = app.end_time;
        
    
                app.update_widgets_from_track_data();

            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            try
                checkedNodes = app.Tree.CheckedNodes;
                app.selected_individuals = {checkedNodes.Text};
            catch e
                app.update_status_text(e.getReport);
            end
            
        end

        % Button pushed function: SelectgriddeddataButton
        function SelectgriddeddataButtonPushed(app, event)
            try
                % Open file browser to select input data 
                [gridded_datafile, gridded_path] = uigetfile('*.nc', 'select');
                app.gridded_data = GriddedData(fullfile(gridded_path, gridded_datafile));
                app.FilepathTextArea_gridded.Value = app.gridded_data.filename;
                
                % Get variable names 
                app.VariabletoanimateDropDown_gridded.Items = app.gridded_data.varnames;
                app.TimevariableDropDown_gridded.Items = app.gridded_data.varnames; 
                app.LatitudevariableDropDown_gridded.Items = app.gridded_data.varnames; 
                app.LongitudevariableDropDown_gridded.Items = app.gridded_data.varnames;
                
                % Auto-select time, lat, and long
                app.autoselect_valid_varnames(app.gridded_data, 'TimevariableDropDown_gridded', ...
                    'LatitudevariableDropDown_gridded', 'LongitudevariableDropDown_gridded', ...
                    'VariabletoanimateDropDown_gridded')
            catch e
                app.update_status_text(e.getReport);
            end
 
        end

        % Button pushed function: CreateanimationButton
        function CreateanimationButtonPushed(app, event)
            try
                
                app.update_status_text("Generating animation... Please be patient");
    
                app.save_settings()
                % Explicit mode switch: presence vs tracks
                use_presence_mode = app.UsepresencevisualizationoptionsCheckBox.Value;
                % tracks colors
                if app.UsecustomcolorsCheckBox.Value && ~isempty(app.track_colors)
                    track_cmap = cmap_from_colors(app.track_colors);
                else
                    track_cmap = lines;
                end
                
                % Presence colors
                if app.UsecustomcolorsCheckBox_3.Value && ~isempty(app.presence_colors)
                    presence_cmap = app.presence_colors;
                else
                    presence_cmap = [];
                end
                
                % Track marker color 
                if app.UseblackmarkersCheckBox.Value
                    track_marker_color = 'k';
                else
                    track_marker_color = NaN;
                end

                %check presence parameters 
                if use_presence_mode
                    if isempty(app.raw_track_data)
                        app.update_status_text('Presence visualization options are enabled, but no data are loaded.');
                        return
                    end
                
                    vars = app.raw_track_data.Properties.VariableNames;
                
                    if isempty(app.SizeparameterDropDown.Value) || ~ismember(app.SizeparameterDropDown.Value, vars)
                        app.update_status_text('Presence mode is enabled, but Size parameter is invalid or missing in the loaded data.');
                        return
                    end
                
                    if isempty(app.ColorparameterDropDown.Value) || ~ismember(app.ColorparameterDropDown.Value, vars)
                        app.update_status_text('Presence mode is enabled, but Color parameter is invalid or missing in the loaded data.');
                        return
                    end
                end

                % Gather gridded data info 
                if ~isempty(app.gridded_data) && ~isa(app.gridded_data,'StaticGriddedData')
                    app.gridded_data.latvar = app.LatitudevariableDropDown_gridded.Value;
                    app.gridded_data.lonvar = app.LongitudevariableDropDown_gridded.Value;
                    app.gridded_data.timevar = app.TimevariableDropDown_gridded.Value;
                    app.gridded_data.var_of_interest = app.VariabletoanimateDropDown_gridded.Value;
                    app.gridded_data.cmap = app.ColormapDropDown_gridded.Value;
                    app.gridded_data.invert_cmap = app.InvertcolormapCheckBox.Value;
                    if app.AutomaticcolorbarlimitsCheckBox.Value
                        app.gridded_data.cbar_limits = [];
                    else
                        app.gridded_data.cbar_limits = [app.ColorbarminEditField.Value, app.ColorbarmaxEditField.Value];
                    end
                    app.gridded_data.show_colorbar = app.ShowcolorbarCheckBox.Value;
                    app.gridded_data.load_time_index();
                end

                % Gather static NetCDF display options
                if ~isempty(app.gridded_data) && isa(app.gridded_data, 'StaticGriddedData')
                    app.gridded_data.cmap = s_resolve_cmap(char(app.StaticCmapDropDown.Value), 256);
                    app.gridded_data.invert_cmap = app.StaticInvertCheckBox.Value;
                    app.gridded_data.show_colorbar = app.StaticShowColorbarCheckBox.Value;
                
                    if app.AutomaticcolorbarlimitsStaticNcCheckBox.Value
                        app.gridded_data.cbar_limits = [];
                    else
                        app.gridded_data.cbar_limits = collectCLim(app);
                    end
                end
                
                % Gather static GeoTIFF display options
                if ~isempty(app.raster_image) && isa(app.raster_image, 'containers.Map') && ...
                        isKey(app.raster_image, 'filename')
                
                    app.raster_image('invert_cmap') = app.InvertcolormapCheckBox_3.Value;
                    app.raster_image('show_colorbar') = app.ShowcolorbarCheckBox_3.Value;
                
                    if app.AutomaticcolorbarlimitsCheckBox_3.Value
                        app.raster_image('cbar_limits') = [];
                    else
                        lo = app.ColorbarminEditField_3.Value;
                        hi = app.ColorbarmaxEditField_3.Value;
                        if isnan(lo) || isnan(hi) || hi <= lo
                            app.raster_image('cbar_limits') = [];
                        else
                            app.raster_image('cbar_limits') = [lo hi];
                        end
                    end
                end
    
                % Gather contour data info 
                if isKey(app.contour_data, 'filename')
                    app.contour_data('latvar') = app.LatitudevariableDropDown_contour.Value;
                    app.contour_data('lonvar') = app.LongitudevariableDropDown_contour.Value; 
                    app.contour_data('timevar') = app.TimevariableDropDown_contour.Value;
                    app.contour_data('var_of_interest') = app.VariabletoanimateDropDown_contour.Value;
                    app.contour_data('LineColor') = app.ContourlinecolorDropDown.Value;
                    app.contour_data('LineWidth') = app.ContourlinewidthSpinner.Value;
                    if app.ShowcontourtextCheckBox.Value; app.contour_data('ShowText') = 'on'; 
                    elseif ~app.ShowcontourtextCheckBox.Value; app.contour_data('ShowText') = 'off'; end
                end

                % Gather quiver data info 
                if ~isempty(app.quiver_data)

                    % Variable and dimension labels
                    app.quiver_data.latvar = app.LatitudevariableDropDown_vectorfield.Value;
                    app.quiver_data.lonvar = app.LongitudevariableDropDown_vectorfield.Value;
                    app.quiver_data.timevar = app.TimevariableDropDown_vectorfield.Value;
                    app.quiver_data.u_var = app.U_vector_DropDown.Value;
                    app.quiver_data.v_var = app.V_vector_DropDown.Value;

                    % Quiver settings
                    app.quiver_data.quiver_color = app.QuivercolorDropDown.Value;
                    app.quiver_data.quiver_speed = app.QuiverspeedSpinner.Value;
                    if app.AutoscaleCheckBox.Value
                        app.quiver_data.quiver_size = 0;
                    else
                        app.quiver_data.quiver_size= app.QuiversizeSpinner.Value;
                    end
                    
                    app.quiver_data.load_time_index();
                end

                % Gather elevation info 
                if app.IncludeelevationcontoursCheckBox.Value
                    app.elevation('LineColor') = app.ContourlinecolorDropDown_elevation.Value;
                    app.elevation('nlevels') = app.NumberoflevelsSpinner.Value;
                    app.elevation('LineWidth') = app.ContourlinewidthSpinner_elevation.Value;
                    app.elevation('ShowText') = app.ShowcontourtextCheckBox_elevation.Value;
                end

                % Gather labeled points info
                labeled_points_loaded = containers.Map();
                
                if isKey(app.labeled_points, 'filename')
                    app.labeled_points('marker_color') = app.MarkercolorDropDown_labeledPoints.Value;
                    app.labeled_points('marker_size') = app.MarkersizeSpinner_labeledPoints.Value;
                    app.labeled_points('text_size') = app.LabelsizeSpinner_labeledPoints.Value;
                    app.labeled_points('text_color') = app.LabelcolorDropDown_labeledPoints.Value;
                    app.labeled_points('show_text') = app.ShowlabeltextCheckBox.Value;
                
                    if app.ColorpointsbyattributeCheckBox.Value
                        app.labeled_points('group_by') = app.ColorpointsbyDropDown.Value;
                    else
                        app.labeled_points('group_by') = NaN;
                    end
                
                    if app.ColorpointsbyattributeCheckBox.Value && ...
                            app.UsecustomcolorsCheckBox_labeledPoints.Value && ...
                            ~isempty(app.labeled_points_colors)
                        labeledpoints_cmap = cmap_from_colors(app.labeled_points_colors);
                    else
                        labeledpoints_cmap = lines;
                    end
                
                    labeled_points_loaded = LabeledPoints( ...
                        app.labeled_points('filename'), ...
                        marker_color=app.labeled_points('marker_color'), ...
                        marker_size=app.labeled_points('marker_size'), ...
                        text_color=app.labeled_points('text_color'), ...
                        text_size=app.labeled_points('text_size'), ...
                        show_text=app.labeled_points('show_text'), ...
                        group_by=app.labeled_points('group_by'), ...
                        cmap=labeledpoints_cmap);
                end

                % Update tracks / presence info
                if use_presence_mode
                    app.tracks.visualization_mode = 'presence';
                    app.tracks.frequency = hours(app.VisualiztionfrequencyhoursSpinner.Value);
                    app.tracks.track_alpha = app.LocationopacitySpinner.Value;
                    app.tracks.marker_style = app.MarkerstyleDropDown_3.Value;
                    app.tracks.track_memory = app.LocationmemoryhoursEditField.Value;
                    app.tracks.use_black_markers_only = app.UseblackmarkersonlyCheckBox.Value;
                    app.tracks.size_parameter = app.SizeparameterDropDown.Value;
                    app.tracks.color_parameter = app.ColorparameterDropDown.Value;
                    app.tracks.presence_colors = presence_cmap;
                
                    % Safe defaults for presence mode
                    app.tracks.marker_color = NaN;
                    app.tracks.marker_size = 100;
                    app.tracks.track_width = 0;
                    app.tracks.fade_tracks = false;
                
                    % Keep legacy fields stable
                    app.tracks.group_by = 'individual_local_identifier';
                    app.tracks.track_cmap = lines;
                else
                    app.tracks.visualization_mode = 'track';
                    app.tracks.track_cmap = track_cmap;
                    app.tracks.frequency = hours(app.TrackfrequencyhoursSpinner.Value);
                    app.tracks.track_alpha = app.TrackopacitySpinner.Value;
                    app.tracks.marker_color = track_marker_color;
                    app.tracks.marker_size = app.MarkersizeSpinner_tracks.Value;
                    app.tracks.marker_style = app.MarkerstyleDropDown.Value;
                    app.tracks.fade_tracks = app.FadetracksCheckBox.Value;
                    app.tracks.track_memory = app.TrackmemoryEditField.Value;
                    app.tracks.group_by = app.ColortracksbyDropDown.Value;
                    app.tracks.track_width = app.TracklinewidthSpinner.Value;
                
                    % Clear presence-specific state
                    app.tracks.use_black_markers_only = false;
                    app.tracks.size_parameter = '';
                    app.tracks.color_parameter = '';
                    app.tracks.presence_colors = [];
                end
                
                %check which legend is used
                if use_presence_mode
                    show_legend_value = app.ShowlegendCheckBox_3.Value;
                    anim_start_time = app.start_time;
                    anim_end_time = app.end_time;
                else
                    show_legend_value = app.ShowlegendCheckBox.Value;
                    anim_start_time = dateshift(app.start_time, 'start', 'day');
                    anim_end_time = dateshift(app.end_time, 'start', 'day');
                end
                
                animate_tracks(app.tracks,...
                    gridded_data=app.gridded_data, ...
                    contour_data=app.contour_data, ...
                    quiver_data=app.quiver_data, ...
                    elevation=app.elevation, ...
                    shapefile_stack=app.shapefile_stack, ...
                    raster_image=app.raster_image, ...
                    raster_cmap=app.ColormapDropDown_gridded_3.Value, ...
                    labeled_points=labeled_points_loaded, ...
                    start_time=anim_start_time, ...
                    end_time=anim_end_time, ...
                    output_directory=app.output_dir, ...
                    latmin=app.MinlatitudeEditField.Value, latmax=app.MaxlatitudeEditField.Value, ...
                    lonmin=app.MinlongitudeEditField.Value, lonmax=app.MaxlongitudeEditField.Value, ...
                    last_frame_only=app.GeneratelastframeonlyCheckBox.Value, ...
                    show_legend=show_legend_value)
    
                app.update_status_text("Animation saved to the output directory.");
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: SetoutputdirectoryButton
        function SetoutputdirectoryButtonPushed(app, event)
            try
                app.output_dir = uigetdir();
                app.FilepathTextArea_output.Value = app.output_dir; 
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Value changed function: StartdateDatePicker
        function StartdateDatePickerValueChanged(app, event)
            try
                app.start_time = app.StartdateDatePicker.Value;
            catch e
                app.update_status_text(e.getReport);
            end            
        end

        % Value changed function: EnddateDatePicker
        function EnddateDatePickerValueChanged(app, event)
            try
                app.end_time = app.EnddateDatePicker.Value;
            catch e
                app.update_status_text(e.getReport);
            end            
        end

        % Button pushed function: UpdatefiltersButton
        function UpdatefiltersButtonPushed(app, event)
            try
                app.apply_track_filters()
                app.NumberofpointsinselectionTextArea.Value = num2str(height(app.tracks.data));
                app.NumberofindividualsselectedTextArea.Value = num2str(length(unique(app.tracks.data.individual_local_identifier)));
                app.EarliesttimestampinselectionTextArea.Value = string(min(app.tracks.data.timestamp));
                app.LatesttimestampinselectionTextArea.Value = string(max(app.tracks.data.timestamp));
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: SelectcontourdataButton
        function SelectcontourdataButtonPushed(app, event)
            try
                [datafile, path] = uigetfile('*.nc', 'select');
                app.contour_data('filename') = fullfile(path, datafile);
                app.FilepathTextArea_contour.Value = app.contour_data('filename');
                
                % Get variable names 
                varnames= {ncinfo(app.contour_data('filename')).Variables.Name};
                app.VariabletoanimateDropDown_contour.Items = varnames;
                app.TimevariableDropDown_contour.Items = varnames; 
                app.LatitudevariableDropDown_contour.Items = varnames; 
                app.LongitudevariableDropDown_contour.Items = varnames;

                app.contour_data("varnames") = varnames;

            
                % Auto-select time, lat, and long
                app.autoselect_valid_varnames(app.contour_data, 'TimevariableDropDown_contour', ...
                    'LatitudevariableDropDown_contour', 'LongitudevariableDropDown_contour', ...
                    'VariabletoanimateDropDown_contour')
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: SelectshapefileButton
        function SelectshapefileButtonPushed(app, event)
            try
                [datafile, path] = uigetfile('*.shp', 'select');
                filename = fullfile(path, datafile);
                app.FilepathTextArea_shapefile.Value = filename;

                shapefile = Shapefile(filename);

                if shapefile.is_point()
                    app.PointcolorDropDown_shapefile.Editable = true;
                    app.PointcolorDropDown_shapefile.Enable = true;
                    app.MarkersizeSpinner_shapefile.Editable = true;
                    app.MarkersizeSpinner_shapefile.Enable = true;
                    app.MarkerstyleDropDown_shapefile.Editable = true;
                    app.MarkerstyleDropDown_shapefile.Enable = true;
                    app.LinecolorDropDown.Editable=false;
                    app.LinecolorDropDown.Enable=false;
                    app.LinewidthSpinner.Editable=false;
                    app.LinewidthSpinner.Enable=false;
                    app.EdgecolorDropDown.Editable = false;
                    app.EdgecolorDropDown.Enable = false;
                    app.FacecolorDropDown.Editable = false;
                    app.FacecolorDropDown.Enable = false; 
                    app.FaceopacitySpinner.Editable = false;
                    app.FaceopacitySpinner.Enable = false; 
                    shapefile.point_color = app.PointcolorDropDown_shapefile.Value;
                    shapefile.marker_style = app.MarkerstyleDropDown_shapefile.Value;    
                    shapefile.marker_size = app.MarkersizeSpinner_shapefile.Value;
                    point_line_edge_color = app.PointcolorDropDown_shapefile.Value;
                elseif shapefile.is_line()
                    app.PointcolorDropDown_shapefile.Editable = false;
                    app.PointcolorDropDown_shapefile.Enable = false;
                    app.MarkersizeSpinner_shapefile.Editable = false;
                    app.MarkersizeSpinner_shapefile.Enable = false;
                    app.MarkerstyleDropDown_shapefile.Editable = false;
                    app.MarkerstyleDropDown_shapefile.Enable = false;                    
                    app.LinecolorDropDown.Editable=true;
                    app.LinecolorDropDown.Enable=true;
                    app.LinewidthSpinner.Editable=true;
                    app.LinewidthSpinner.Enable=true;
                    app.EdgecolorDropDown.Editable = false;
                    app.EdgecolorDropDown.Enable = false;
                    app.FacecolorDropDown.Editable = false;
                    app.FacecolorDropDown.Enable = false; 
                    app.FaceopacitySpinner.Editable = false;
                    app.FaceopacitySpinner.Enable = false; 
                    shapefile.line_color = app.LinecolorDropDown.Value;
                    shapefile.line_width = app.LinewidthSpinner.Value;
                    point_line_edge_color = app.LinecolorDropDown.Value;
                elseif shapefile.is_poly()
                    app.PointcolorDropDown_shapefile.Editable = false;
                    app.PointcolorDropDown_shapefile.Enable = false;
                    app.MarkersizeSpinner_shapefile.Editable = false;
                    app.MarkersizeSpinner_shapefile.Enable = false;
                    app.MarkerstyleDropDown_shapefile.Editable = false;
                    app.MarkerstyleDropDown_shapefile.Enable = false;                    
                    app.EdgecolorDropDown.Editable = true;
                    app.EdgecolorDropDown.Enable = true;
                    app.FacecolorDropDown.Editable = true;
                    app.FacecolorDropDown.Enable = true; 
                    app.FaceopacitySpinner.Editable = true;
                    app.FaceopacitySpinner.Enable = true; 
                    app.LinecolorDropDown.Editable=false;
                    app.LinecolorDropDown.Enable=false;
                    app.LinewidthSpinner.Editable=false;
                    app.LinewidthSpinner.Enable=false;
                    shapefile.edge_color= app.EdgecolorDropDown.Value;
                    shapefile.face_color = app.FacecolorDropDown.Value; 
                    shapefile.face_alpha = app.FaceopacitySpinner.Value; 
                    point_line_edge_color = app.EdgecolorDropDown.Value;
                    
                end

                % Update table display

                if length(app.shapefile_stack)<1
                    % Initialize table if needed
                    varnames = ["File","Geometry","Point/Line/Edge color","Marker style","Marker size","Line width","Face color","Face opacity"];
                    vartypes = ["string", "string", "string", "string", "double", "double", "string", "double"];
                    tablesize=[0,8];
                    app.UITable_shapefiles.Data = table('VariableNames', varnames, 'VariableTypes', vartypes, 'Size', tablesize);
                end

                app.UITable_shapefiles.Data(end +1, :) = {datafile, shapefile.geometry_type, point_line_edge_color, shapefile.marker_style, shapefile.marker_size, shapefile.line_width, shapefile.face_color, shapefile.face_alpha};

                app.shapefile_stack{end+1} = shapefile;

                % Disable adding new shapefiles if at max limit
                max_shapefiles = 15;
                if length(app.shapefile_stack) >= max_shapefiles
                    app.SelectshapefileButton.Enable = false;
                end

            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Value changed function: LinecolorDropDown
        function LinecolorDropDownValueChanged(app, event)
            try
                app.shapefile_stack{end}.line_color = app.LinecolorDropDown.Value;
                app.UITable_shapefiles.Data(end, "Point/Line/Edge color") = {app.LinecolorDropDown.Value};
            catch e
                app.update_status_text(e.getReport);
            end            
        end

        % Value changed function: LinewidthSpinner
        function LinewidthSpinnerValueChanged(app, event)
            try
                app.shapefile_stack{end}.line_width = app.LinewidthSpinner.Value;
                app.UITable_shapefiles.Data(end, "Line width") = {app.LinewidthSpinner.Value};
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Value changed function: EdgecolorDropDown
        function EdgecolorDropDownValueChanged(app, event)
            try
                app.shapefile_stack{end}.edge_color = app.EdgecolorDropDown.Value;
                app.UITable_shapefiles.Data(end, "Point/Line/Edge color") = {app.EdgecolorDropDown.Value};
            catch e
                app.update_status_text(e.getReport);
            end           
        end

        % Value changed function: FacecolorDropDown
        function FacecolorDropDownValueChanged(app, event)
            try
                app.shapefile_stack{end}.face_color = app.FacecolorDropDown.Value;
                app.UITable_shapefiles.Data(end, "Face color") = {app.FacecolorDropDown.Value};
            catch e
                app.update_status_text(e.getReport);
            end       
        end

        % Value changed function: FaceopacitySpinner
        function FaceopacitySpinnerValueChanged(app, event)
            try
                app.shapefile_stack{end}.face_alpha = app.FaceopacitySpinner.Value;
                app.UITable_shapefiles.Data(end, "Face opacity") = {app.FaceopacitySpinner.Value};
            catch e
                app.update_status_text(e.getReport);
            end            
        end

        % Button pushed function: SelectfileButton_labeledPoints
        function SelectfileButton_labeledPointsPushed(app, event)
            try
                [datafile, path] = uigetfile('*.csv', 'select');
                app.labeled_points('filename') = fullfile(path, datafile);
                app.FilepathTextArea_labeledPoints.Value = app.labeled_points('filename'); 

                labels = readtable(app.labeled_points('filename'));
                vars = labels.Properties.VariableNames;
                app.ColorpointsbyDropDown.Items = vars;
                if ~isempty(vars)
                     app.ColorpointsbyDropDown.Value = vars{1};
                else
                    app.ColorpointsbyDropDown.Value = {};
                end
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: ClearcolorsButton, ClearcolorsButton_3, 
        % ...and 1 other component
        function ClearcolorsButtonPushed(app, event)
            try
                src = event.Source;

                if src == app.ClearcolorsButton
                    app.track_colors = [];
                    app.UITable_colors.Data = [];
                    app.AddcolorButton.Enable = 'on';

                elseif src == app.ClearcolorsButton_3
                    app.presence_colors = [];
                    app.UITable_colors_3.Data = [];
                    app.AddcolorButton_3.Enable = 'on';

                elseif src == app.ClearcolorsButton_labeledPoints
                    app.labeled_points_colors = [];
                    app.UITable_colors_labeledPoints.Data = [];
                    app.AddcolorButton_labeledPoints.Enable = 'on';
                end

            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: AddcolorButton, AddcolorButton_3, 
        % ...and 1 other component
        function AddcolorButtonPushed(app, event)
            try
                c = uisetcolor;
                if numel(c) ~= 3
                    return;
                end

                src = event.Source;

                if src == app.AddcolorButton
                    app.track_colors(end+1, :) = c;
                    app.UITable_colors.Data = app.track_colors;

                    if size(app.track_colors, 1) >= 30
                        app.AddcolorButton.Enable = 'off';
                    end

                elseif src == app.AddcolorButton_3
                    app.presence_colors(end+1, :) = c;
                    app.UITable_colors_3.Data = app.presence_colors;

                    if size(app.presence_colors, 1) >= 30
                        app.AddcolorButton_3.Enable = 'off';
                    end

                elseif src == app.AddcolorButton_labeledPoints
                    app.labeled_points_colors(end+1, :) = c;
                    app.UITable_colors_labeledPoints.Data = app.labeled_points_colors;
                
                    if size(app.labeled_points_colors, 1) >= 30
                        app.AddcolorButton_labeledPoints.Enable = 'off';
                    end
                end

            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: ClearallshapefilesButton
        function ClearallshapefilesButtonPushed(app, event)
            try
                app.shapefile_stack = {};
                app.UITable_shapefiles.Data = [];
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: ClearlastshapefileButton
        function ClearlastshapefileButtonPushed(app, event)
            try
                app.shapefile_stack(end) = [];
                app.UITable_shapefiles.Data = app.UITable_shapefiles.Data(1:end-1, :);
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: LoadsettingsButton
        function LoadsettingsButtonPushed(app, event)
            try
                app.update_status_text("Loading settings...");
                [settings_file, settings_path] = uigetfile('*.mat', "Load settings");
                file = fullfile(settings_path, settings_file);
                if file
                   load(file, 'props', 'values', 'data', 'items', 'visibilities', 'var_map');
                end

                % Reset the individuals tree node 
                individual_nodes = app.AllindividualsNode.Children;
                individual_nodes.delete;

                % Re-populate widgets that were dependent on the track data
                keys = var_map.keys;
                for i=1:length(keys)
                    app.(keys{i}) = var_map(keys{i});
                end
                app.update_widgets_from_track_data();

                % Re-populate the selected individuals in the tree
                checkedNodes = matlab.ui.container.TreeNode.empty(length(app.selected_individuals), 0);
                for i = 1:length(app.selected_individuals)
                    checkedNodes(i) = app.Tree.findobj('Text', app.selected_individuals{i});
                end
                app.Tree.CheckedNodes = checkedNodes;

                for i = 1:length(props)
                    propName = props{i};
                    property = app.(propName);
                    if isprop(property, 'Items')
                        app.(propName).Items = items{i};
                    end
                    if isprop(property, 'Value')
                        app.(propName).Value = values{i};
                    end
                    if isprop(property, 'Data')
                        app.(propName).Data = data{i};
                    end
                    if isprop(property, 'Visible')
                        app.(props{i}).Visible = visibilities{i};
                    end
                end
                
                app.update_status_text("Settings loaded from " + file);
            catch e
                app.update_status_text("Error loading settings..." + newline + newline + e.getReport);
            end
        end

        % Button pushed function: CleardataButton_gridded
        function Clear_gridded_data_ButtonPushed(app, event)
            app.gridded_data = {};
            app.FilepathTextArea_gridded.Value = '';
            app.TimevariableDropDown_gridded.Items = {};
            app.LatitudevariableDropDown_gridded.Items = {};
            app.LongitudevariableDropDown_gridded.Items = {};
            app.VariabletoanimateDropDown_gridded.Items = {};
            app.ColormapDropDown_gridded.Value = app.ColormapDropDown_gridded.Items(1);
            app.InvertcolormapCheckBox.Value = false;
            app.ColorbarminEditField.Value = 0;
            app.ColorbarmaxEditField.Value = 1;
            app.AutomaticcolorbarlimitsCheckBox.Value = false; 
            app.ShowcolorbarCheckBox.Value = true;

        end

        % Button pushed function: CleardataButton_contour
        function Clear_contour_data_ButtonPushed(app, event)
            app.contour_data = containers.Map();
            app.FilepathTextArea_contour.Value = '';
            app.TimevariableDropDown_contour.Items = {};
            app.LatitudevariableDropDown_contour.Items = {};
            app.LongitudevariableDropDown_contour.Items = {};
            app.VariabletoanimateDropDown_contour.Items = {};
            app.ContourlinecolorDropDown.Value = app.ContourlinecolorDropDown.Items(1);
            app.ContourlinewidthSpinner.Value = 0.7;
            app.ShowcontourtextCheckBox.Value = false;
        end

        % Button pushed function: CleardataButton_labeledPoints
        function CleardataButton_labeledPointsPushed(app, event)
            app.labeled_points = containers.Map();
            app.FilepathTextArea_labeledPoints.Value = '';
            app.MarkercolorDropDown_labeledPoints.Value = app.MarkercolorDropDown_labeledPoints.Items(1);
            app.MarkersizeSpinner_labeledPoints.Value = 20;
            app.LabelcolorDropDown_labeledPoints.Value = 'black';
            app.LabelsizeSpinner_labeledPoints.Value = 8;
            app.ShowlabeltextCheckBox.Value = true;
            app.ColorpointsbyattributeCheckBox.Value = false;
            app.ColorpointsbyDropDown.Items = {};
            app.ColorpointsbyDropDown.Value = {};
            app.UsecustomcolorsCheckBox_labeledPoints.Value = false;
            app.labeled_points_colors = [];
            app.UITable_colors_labeledPoints.Data = [];
            app.AddcolorButton_labeledPoints.Enable = 'on';
        end

        % Value changed function: PointcolorDropDown_shapefile
        function PointcolorDropDown_shapefileValueChanged(app, event)
            try
                app.shapefile_stack{end}.point_color = app.PointcolorDropDown_shapefile.Value;
                app.UITable_shapefiles.Data(end, "Point/Line/Edge color") = {app.PointcolorDropDown_shapefile.Value};
            catch e
                app.update_status_text(e.getReport);
            end   
        end

        % Value changed function: MarkerstyleDropDown_shapefile
        function MarkerstyleDropDown_shapefileValueChanged(app, event)
            try
                app.shapefile_stack{end}.marker_style = app.MarkerstyleDropDown_shapefile.Value;
                app.UITable_shapefiles.Data(end, "Marker style") = {app.MarkerstyleDropDown_shapefile.Value};
            catch e
                app.update_status_text(e.getReport);
            end              
        end

        % Value changed function: MarkersizeSpinner_shapefile
        function MarkersizeSpinner_shapefileValueChanged(app, event)
            try
                app.shapefile_stack{end}.marker_size = app.MarkersizeSpinner_shapefile.Value;
                app.UITable_shapefiles.Data(end, "Marker size") = {app.MarkersizeSpinner_shapefile.Value};
            catch e
                app.update_status_text(e.getReport);
            end  
        end

        % Button pushed function: SelectvectorfielddataButton
        function SelectvectorfielddataButtonPushed(app, event)
            try
                % Open file browser to select input data 
                [datafile, path] = uigetfile('*.nc', 'select');
                app.quiver_data = Quivers(fullfile(path, datafile));
                app.FilepathTextArea_vectorfield.Value = app.quiver_data.filename;
                
                % Get variable names 
                app.U_vector_DropDown.Items = app.quiver_data.varnames;
                app.V_vector_DropDown.Items = app.quiver_data.varnames;
                app.TimevariableDropDown_vectorfield.Items = app.quiver_data.varnames; 
                app.LatitudevariableDropDown_vectorfield.Items = app.quiver_data.varnames; 
                app.LongitudevariableDropDown_vectorfield.Items = app.quiver_data.varnames;
                
                % Auto-select time, lat, and long
                app.autoselect_valid_varnames(app.quiver_data, 'TimevariableDropDown_vectorfield', ...
                    'LatitudevariableDropDown_vectorfield', 'LongitudevariableDropDown_vectorfield', ...
                    struct('u', 'U_vector_DropDown', 'v', 'V_vector_DropDown'))
            catch e
                app.update_status_text(e.getReport);
            end
        end

        % Button pushed function: CleardataButton_vectorfield
        function CleardataButton_vectorfieldPushed(app, event)
            app.quiver_data = {};
            app.FilepathTextArea_vectorfield.Value = '';
            app.TimevariableDropDown_vectorfield.Items = {};
            app.LatitudevariableDropDown_vectorfield.Items = {};
            app.LongitudevariableDropDown_vectorfield.Items = {};
            app.U_vector_DropDown.Items = {};
            app.V_vector_DropDown.Items = {};
            app.QuivercolorDropDown.Value = app.QuivercolorDropDown.Items(1);
        end

        % Button pushed function: SelectstaticdataButton
        function BrowseStaticNcButtonPushed(app, event)
            [fn, fp] = uigetfile({'*.nc;*.netcdf','NetCDF files (*.nc, *.netcdf)'}, ...
                         'Select static NetCDF file');
            if isequal(fn,0)
                return;
            end
        
            fullpath = fullfile(fp, fn);
            app.StaticNcFile = fullpath;
            app.StaticNcDataPathTextArea.Value = fullpath;
        
            try
                app.populateStaticNcDropdowns(fullpath);
                app.loadStaticLayerIntoApp();
                app.StatusTextArea.Value = sprintf("Loaded static layer: %s", fullpath);
            catch ME
                uialert(app.ECODATAAnimateUIFigure, ...
                    sprintf('Error loading static NetCDF:\n%s', ME.message), ...
                    'Static NetCDF error');
            end
        end

        % Value changed function: StaticLatVarDropDown
        function StaticLatVarDropDownValueChanged(app, event)
            app.StaticLatVar = app.StaticLatVarDropDown.Value;
             if ~isempty(app.StaticNcFile)
                try
                    app.loadStaticLayerIntoApp();
                catch ME
                    uialert(app.ECODATAAnimateUIFigure, ...
                        sprintf('Error reloading static NetCDF layer:\n%s', ME.message), ...
                        'Static NetCDF error');
                end
            end
        end

        % Value changed function: StaticLonVarDropDown
        function StaticLonVarDropDownValueChanged(app, event)
            app.StaticLonVar = app.StaticLonVarDropDown.Value;
             if ~isempty(app.StaticNcFile)
                try
                    app.loadStaticLayerIntoApp();
                catch ME
                    uialert(app.ECODATAAnimateUIFigure, ...
                        sprintf('Error reloading static NetCDF layer:\n%s', ME.message), ...
                        'Static NetCDF error');
                end
            end
        end

        % Value changed function: StaticVarDropDown
        function StaticVarDropDownValueChanged(app, event)
            app.StaticVar = app.StaticVarDropDown.Value;
            if ~isempty(app.StaticNcFile)
                try
                    app.loadStaticLayerIntoApp();
                catch ME
                    uialert(app.ECODATAAnimateUIFigure, ...
                        sprintf('Error reloading static NetCDF layer:\n%s', ME.message), ...
                        'Static NetCDF error');
                end
            end
        end

        % Value changed function: StaticCmapDropDown
        function StaticCmapDropDownValueChanged(app, event)
            app.StaticCmapName = app.StaticCmapDropDown.Value;
            if ~isempty(app.gridded_data) && isa(app.gridded_data,'StaticGriddedData')
                cmapName = char(app.StaticCmapDropDown.Value);
                app.gridded_data.cmap = s_resolve_cmap(cmapName, 256);
            end
        end

        % Value changed function: StaticInvertCheckBox
        function StaticInvertCheckBoxValueChanged(app, event)
            app.StaticInvertCmap = app.StaticInvertCheckBox.Value;
            if ~isempty(app.gridded_data) && isa(app.gridded_data,'StaticGriddedData')
                app.gridded_data.invert_cmap = logical(app.StaticInvertCheckBox.Value);
            end
        end

        % Value changed function: StaticShowColorbarCheckBox
        function StaticShowColorbarCheckBoxValueChanged(app, event)
            app.StaticShowColorbar = app.StaticShowColorbarCheckBox.Value;
            if ~isempty(app.gridded_data) && isa(app.gridded_data,'StaticGriddedData')
                app.gridded_data.show_colorbar = logical(app.StaticShowColorbarCheckBox.Value);
            end
        end

        % Value changed function: StaticCLimMinField
        function StaticCLimMinFieldValueChanged(app, event)
            app.StaticCLim = collectCLim(app);
        end

        % Value changed function: StaticCLimMaxField
        function StaticCLimMaxFieldValueChanged(app, event)
            app.StaticCLim = collectCLim(app);
        end

        % Button pushed function: SelectgriddeddataButton_3
        function SelectgriddeddataButton_3Pushed(app, event)
            app.BrowseStaticGeoTIFFButtonPushed(event);
        end

        % Callback function
        function CleardataButton_gridded_3Pushed(app, event)
            app.raster_image = containers.Map();
            app.FilepathTextArea_gridded_3.Value = '';
            app.DatasetdimensionsLabel_5.Text = 'Dataset dimensions:';
        end

        % Value changed function: StaticTimeDropDown
        function StaticTimeDropDownValueChanged(app, event)
            choice = char(app.StaticTimeDropDown.Value);
            if strcmp(choice, 'No time variable')
                % Disable time slice selection
                app.TimeSliceDropDown.Items   = {};
                app.TimeSliceDropDown.Value   = {};
                app.TimeSliceDropDown.Enable  = 'off';
        
                % Optionally reload static layer as pure 2-D (if already have file)
                if ~isempty(app.StaticNcFile)
                    app.loadStaticLayerIntoApp();
                end
                return;
            end
        
            % Real time variable selected
            timevar = choice;
        
            try
                tvals = read_nc_timestamps(app.StaticNcFile, timevar);
            catch ME
                uialert(app.ECODATAAnimateUIFigure, ...
                    sprintf('Cannot read time variable "%s":\n%s', timevar, ME.message), ...
                    'Time variable error');
                app.TimeSliceDropDown.Items   = {};
                app.TimeSliceDropDown.Value   = {};
                app.TimeSliceDropDown.Enable  = 'off';
                return;
            end
        
            if isempty(tvals)
                app.TimeSliceDropDown.Items   = {};
                app.TimeSliceDropDown.Value   = {};
                app.TimeSliceDropDown.Enable  = 'off';
                return;
            end
        
            % Build labels "k: yyyy-mm-dd HH:MM"
            labels = cell(numel(tvals),1);
            for k = 1:numel(tvals)
                if isa(tvals, 'datetime')
                    labels{k} = sprintf('%d: %s', k, datestr(tvals(k), 'yyyy-mm-dd HH:MM'));
                else
                    labels{k} = sprintf('%d: %g', k, tvals(k));
                end
            end
        
            app.TimeSliceDropDown.Items   = labels;
            app.TimeSliceDropDown.Value   = labels{1};
            app.TimeSliceDropDown.Enable  = 'on';
        
            % Auto-reload static layer when time variable (and first slice) chosen
            if ~isempty(app.StaticNcFile)
                app.loadStaticLayerIntoApp();
            end
        end

        % Button pushed function: ClearStaticDataButton
        function StaticNcClearDataButtonPushed(app, event)
            app.gridded_data = [];   %
            app.StaticNcDataPathTextArea.Value = '';
            app.StaticNcFile = '';
            app.StaticVarDropDown.Items = {};
            app.StaticVarDropDown.Value = {};
            app.StaticLatVarDropDown.Items = {};
            app.StaticLatVarDropDown.Value = {};
            app.StaticLonVarDropDown.Items = {};
            app.StaticLonVarDropDown.Value = {};
            app.StaticTimeDropDown.Items = {'No time variable'};
            app.StaticTimeDropDown.Value = 'No time variable';
            app.StaticTimeDropDown.Enable = 'off';
            app.StaticCmapDropDown.Value = app.StaticCmapDropDown.Items{1};
            app.StaticInvertCheckBox.Value = false;
            app.StaticShowColorbarCheckBox.Value = true;
            app.StaticCLimMinField.Value = 0;
            app.StaticCLimMaxField.Value = 1;
            app.StatusTextArea.Value = "Static NetCDF data cleared.";
        end

        % Button pushed function: CleardataButton_gridded_3
        function CleardaStaticTiffClearDataButtonPushedtaButton_gridded_3Pushed(app, event)
            app.raster_image = containers.Map(); 
            app.FilepathTextArea_gridded_3.Value = '';
            app.DatasetdimensionsLabel_5.Text = 'Dataset dimensions:';
            if ~isempty(app.ColormapDropDown_gridded_3.Items)
                app.ColormapDropDown_gridded_3.Value = app.ColormapDropDown_gridded_3.Items{1};
            end
            app.InvertcolormapCheckBox_3.Value = false;
            app.ShowcolorbarCheckBox_3.Value   = true;
            app.AutomaticcolorbarlimitsCheckBox_3.Value = true;
            app.ColorbarminEditField_3.Value = 0;
            app.ColorbarmaxEditField_3.Value = 1;
            app.StatusTextArea.Value = "Static GeoTIFF data cleared.";
        end

        % Value changed function: TimeSliceDropDown
        function TimeSliceDropDownValueChanged(app, event)
            value = app.TimeSliceDropDown.Value;
            if ~isempty(app.StaticNcFile)
                app.loadStaticLayerIntoApp();
            end
        end

        % Value changed function: InvertcolormapCheckBox_3
        function InvertcolormapCheckBox_3ValueChanged(app, event)
            value = app.InvertcolormapCheckBox_3.Value;
            if ~isempty(app.raster_image) && isKey(app.raster_image, 'invert_cmap')
                app.raster_image('invert_cmap') = logical(app.InvertcolormapCheckBox_3.Value);
            end
        end

        % Value changed function: ShowcolorbarCheckBox_3
        function ShowcolorbarCheckBox_3ValueChanged(app, event)
            value = app.ShowcolorbarCheckBox_3.Value;
            if ~isempty(app.raster_image) && isKey(app.raster_image, 'show_colorbar')
                app.raster_image('show_colorbar') = logical(app.ShowcolorbarCheckBox_3.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ECODATAAnimateUIFigure = uifigure('Visible', 'off');
            app.ECODATAAnimateUIFigure.Name = 'ECODATA-Animate';
            app.CreateanimationButton = uibutton(app.ECODATAAnimateUIFigure, 'push');
            app.CreateanimationButton.ButtonPushedFcn = createCallbackFcn(app, @CreateanimationButtonPushed, true);
            app.CreateanimationButton.Text = 'Create animation';
            app.StatusTextAreaLabel = uilabel(app.ECODATAAnimateUIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.ECODATAAnimateUIFigure);
            app.TabGroup = uitabgroup(app.ECODATAAnimateUIFigure);
            app.AnimaltrackdataTab = uitab(app.TabGroup);
            app.AnimaltrackdataTab.Title = 'Animal track data';
            app.SelecttrackdataButton = uibutton(app.AnimaltrackdataTab, 'push');
            app.SelecttrackdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttrackdataButtonPushed, true);
            app.SelecttrackdataButton.Text = 'Select track data';
            app.FilepathTextAreaLabel = uilabel(app.AnimaltrackdataTab);
            app.FilepathTextAreaLabel.Text = 'File path';
            app.FilepathTextArea_tracks = uitextarea(app.AnimaltrackdataTab);
            app.FilepathTextArea_tracks.Editable = 'off';
            app.FilepathTextArea_tracks.Enable = 'off';
            app.DatafiltersPanel = uipanel(app.AnimaltrackdataTab);
            app.DatafiltersPanel.Title = 'Data filters:';
            app.Tree = uitree(app.DatafiltersPanel, 'checkbox');
            app.AllindividualsNode = uitreenode(app.Tree);
            app.AllindividualsNode.Text = 'All individuals';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.IndividualsLabel = uilabel(app.DatafiltersPanel);
            app.IndividualsLabel.Text = 'Individuals: ';
            app.TimerangeLabel = uilabel(app.DatafiltersPanel);
            app.TimerangeLabel.Text = 'Time range: ';
            app.GeographicextentLabel = uilabel(app.DatafiltersPanel);
            app.GeographicextentLabel.Text = 'Geographic extent: ';
            app.UpdatefiltersButton = uibutton(app.DatafiltersPanel, 'push');
            app.UpdatefiltersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatefiltersButtonPushed, true);
            app.UpdatefiltersButton.Text = 'Update filters';
            app.SelectionsummaryLabel = uilabel(app.DatafiltersPanel);
            app.SelectionsummaryLabel.Text = 'Selection summary: ';
            app.StartdateDatePickerLabel = uilabel(app.DatafiltersPanel);
            app.StartdateDatePickerLabel.Text = 'Start date';
            app.StartdateDatePicker = uidatepicker(app.DatafiltersPanel);
            app.StartdateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartdateDatePickerValueChanged, true);
            app.EnddateDatePickerLabel = uilabel(app.DatafiltersPanel);
            app.EnddateDatePickerLabel.Text = 'End date';
            app.EnddateDatePicker = uidatepicker(app.DatafiltersPanel);
            app.EnddateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EnddateDatePickerValueChanged, true);
            app.MinlatitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MinlatitudeEditFieldLabel.Text = 'Min latitude';
            app.MinlatitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MaxlatitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MaxlatitudeEditFieldLabel.Text = 'Max latitude';
            app.MaxlatitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MinlongitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MinlongitudeEditFieldLabel.Text = 'Min longitude';
            app.MinlongitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MaxlongitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MaxlongitudeEditFieldLabel.Text = 'Max longitude';
            app.MaxlongitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.NumberofindividualsselectedTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.NumberofindividualsselectedTextAreaLabel.Text = 'Number of individuals selected:';
            app.NumberofindividualsselectedTextArea = uitextarea(app.DatafiltersPanel);
            app.EarliesttimestampinselectionTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.EarliesttimestampinselectionTextAreaLabel.Text = 'Earliest timestamp in selection:';
            app.EarliesttimestampinselectionTextArea = uitextarea(app.DatafiltersPanel);
            app.LatesttimestampinselectionTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.LatesttimestampinselectionTextAreaLabel.Text = 'Latest timestamp in selection:';
            app.LatesttimestampinselectionTextArea = uitextarea(app.DatafiltersPanel);
            app.NumberofpointsinselectionTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.NumberofpointsinselectionTextAreaLabel.Text = 'Number of points in selection:';
            app.NumberofpointsinselectionTextArea = uitextarea(app.DatafiltersPanel);
            app.TracksvisualizationoptionsTab_main = uitab(app.TabGroup);
            app.TracksvisualizationoptionsTab_main.Title = 'Tracks visualization options';
            app.TabGroup3 = uitabgroup(app.TracksvisualizationoptionsTab_main);
            app.TracksvisualizationoptionsTab = uitab(app.TabGroup3);
            app.TracksvisualizationoptionsTab.Title = 'Tracks visualization options';
            app.AddcolorButton = uibutton(app.TracksvisualizationoptionsTab, 'push');
            app.AddcolorButton.ButtonPushedFcn = createCallbackFcn(app, @AddcolorButtonPushed, true);
            app.AddcolorButton.Text = 'Add color';
            app.FadetracksCheckBox = uicheckbox(app.TracksvisualizationoptionsTab);
            app.FadetracksCheckBox.Text = 'Fade tracks';
            app.TrackmemoryEditField_3Label = uilabel(app.TracksvisualizationoptionsTab);
            app.TrackmemoryEditField_3Label.Text = 'Track memory';
            app.TrackmemoryEditField = uieditfield(app.TracksvisualizationoptionsTab, 'numeric');
            app.TrackmemoryEditField.Limits = [1 Inf];
            app.TrackmemoryEditField.Value = 20;
            app.MarkerstyleDropDown_4Label = uilabel(app.TracksvisualizationoptionsTab);
            app.MarkerstyleDropDown_4Label.Text = 'Marker style';
            app.MarkerstyleDropDown = uidropdown(app.TracksvisualizationoptionsTab);
            app.MarkerstyleDropDown.Items = {'hexagram', 'pentagram', 'square', 'diamond ', 'circle'};
            app.MarkerstyleDropDown.ItemsData = {'hexagram', 'pentagram', 'square', 'diamond ', 'o'};
            app.MarkerstyleDropDown.Value = 'hexagram';
            app.TracklinewidthSpinner_3Label = uilabel(app.TracksvisualizationoptionsTab);
            app.TracklinewidthSpinner_3Label.Text = 'Track line width';
            app.TracklinewidthSpinner = uispinner(app.TracksvisualizationoptionsTab);
            app.TracklinewidthSpinner.Step = 0.1;
            app.TracklinewidthSpinner.Limits = [0.1 5];
            app.TracklinewidthSpinner.Value = 1;
            app.ColortracksbyDropDown_3Label = uilabel(app.TracksvisualizationoptionsTab);
            app.ColortracksbyDropDown_3Label.Text = 'Color tracks by:';
            app.ColortracksbyDropDown = uidropdown(app.TracksvisualizationoptionsTab);
            app.ColortracksbyDropDown.Items = {'individual_local_identifier'};
            app.ColortracksbyDropDown.Placeholder = 'Select attribute';
            app.ColortracksbyDropDown.Value = 'individual_local_identifier';
            app.TrackfrequencyhoursSpinner_3Label = uilabel(app.TracksvisualizationoptionsTab);
            app.TrackfrequencyhoursSpinner_3Label.Text = 'Track frequency (hours)';
            app.TrackfrequencyhoursSpinner = uispinner(app.TracksvisualizationoptionsTab);
            app.TrackfrequencyhoursSpinner.Limits = [0.1 168];
            app.TrackfrequencyhoursSpinner.Value = 24;
            app.TrackopacitySpinner_3Label = uilabel(app.TracksvisualizationoptionsTab);
            app.TrackopacitySpinner_3Label.Text = 'Track opacity';
            app.TrackopacitySpinner = uispinner(app.TracksvisualizationoptionsTab);
            app.TrackopacitySpinner.Step = 0.1;
            app.TrackopacitySpinner.Limits = [0 1];
            app.TrackopacitySpinner.Value = 0.6;
            app.MarkersizeSpinner_2Label_3 = uilabel(app.TracksvisualizationoptionsTab);
            app.MarkersizeSpinner_2Label_3.Text = 'Marker size';
            app.MarkersizeSpinner_tracks = uispinner(app.TracksvisualizationoptionsTab);
            app.MarkersizeSpinner_tracks.Step = 5;
            app.MarkersizeSpinner_tracks.Limits = [1 500];
            app.MarkersizeSpinner_tracks.Value = 150;
            app.ClearcolorsButton = uibutton(app.TracksvisualizationoptionsTab, 'push');
            app.ClearcolorsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearcolorsButtonPushed, true);
            app.ClearcolorsButton.Text = 'Clear colors';
            app.ShowlegendCheckBox = uicheckbox(app.TracksvisualizationoptionsTab);
            app.ShowlegendCheckBox.Text = 'Show legend';
            app.ShowlegendCheckBox.Value = true;
            app.UseblackmarkersCheckBox = uicheckbox(app.TracksvisualizationoptionsTab);
            app.UseblackmarkersCheckBox.Text = 'Use black markers';
            app.UsecustomcolorsCheckBox = uicheckbox(app.TracksvisualizationoptionsTab);
            app.UsecustomcolorsCheckBox.Text = 'Use custom colors';
            app.UITable_colors = uitable(app.TracksvisualizationoptionsTab);
            app.UITable_colors.ColumnName = {'R'; 'G'; 'B'};
            app.UITable_colors.RowName = {};
            app.VisualizationoptionsLabel = uilabel(app.TracksvisualizationoptionsTab);
            app.VisualizationoptionsLabel.Text = 'Visualization options:';
            app.PresencevisualizationoptionsTab = uitab(app.TabGroup3);
            app.PresencevisualizationoptionsTab.Title = 'Presence visualization options';
            app.AddcolorButton_3 = uibutton(app.PresencevisualizationoptionsTab, 'push');
            app.AddcolorButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddcolorButtonPushed, true);
            app.AddcolorButton_3.Text = 'Add color';
            app.LocationmemoryhoursEditFieldLabel = uilabel(app.PresencevisualizationoptionsTab);
            app.LocationmemoryhoursEditFieldLabel.Text = 'Location memory (hours)';
            app.LocationmemoryhoursEditField = uieditfield(app.PresencevisualizationoptionsTab, 'numeric');
            app.LocationmemoryhoursEditField.Limits = [1 Inf];
            app.LocationmemoryhoursEditField.Value = 20;
            app.MarkerstyleDropDown_3Label = uilabel(app.PresencevisualizationoptionsTab);
            app.MarkerstyleDropDown_3Label.Text = 'Marker style';
            app.MarkerstyleDropDown_3 = uidropdown(app.PresencevisualizationoptionsTab);
            app.MarkerstyleDropDown_3.Items = {'hexagram', 'pentagram', 'square', 'diamond ', 'circle'};
            app.MarkerstyleDropDown_3.ItemsData = {'hexagram', 'pentagram', 'square', 'diamond ', 'o'};
            app.MarkerstyleDropDown_3.Value = 'hexagram';
            app.ColorparameterDropDownLabel = uilabel(app.PresencevisualizationoptionsTab);
            app.ColorparameterDropDownLabel.Text = 'Color parameter:';
            app.ColorparameterDropDown = uidropdown(app.PresencevisualizationoptionsTab);
            app.ColorparameterDropDown.Items = {'species'};
            app.ColorparameterDropDown.Placeholder = 'Select attribute';
            app.ColorparameterDropDown.Value = 'species';
            app.VisualiztionfrequencyhoursSpinnerLabel = uilabel(app.PresencevisualizationoptionsTab);
            app.VisualiztionfrequencyhoursSpinnerLabel.Text = 'Visualiztion frequency (hours)';
            app.VisualiztionfrequencyhoursSpinner = uispinner(app.PresencevisualizationoptionsTab);
            app.VisualiztionfrequencyhoursSpinner.Limits = [0.1 168];
            app.VisualiztionfrequencyhoursSpinner.Value = 24;
            app.LocationopacitySpinnerLabel = uilabel(app.PresencevisualizationoptionsTab);
            app.LocationopacitySpinnerLabel.Text = 'Location opacity';
            app.LocationopacitySpinner = uispinner(app.PresencevisualizationoptionsTab);
            app.LocationopacitySpinner.Step = 0.1;
            app.LocationopacitySpinner.Limits = [0 1];
            app.LocationopacitySpinner.Value = 0.6;
            app.ClearcolorsButton_3 = uibutton(app.PresencevisualizationoptionsTab, 'push');
            app.ClearcolorsButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearcolorsButtonPushed, true);
            app.ClearcolorsButton_3.Text = 'Clear colors';
            app.ShowlegendCheckBox_3 = uicheckbox(app.PresencevisualizationoptionsTab);
            app.ShowlegendCheckBox_3.Text = 'Show legend';
            app.ShowlegendCheckBox_3.Value = true;
            app.UseblackmarkersonlyCheckBox = uicheckbox(app.PresencevisualizationoptionsTab);
            app.UseblackmarkersonlyCheckBox.Text = 'Use black markers only';
            app.UsecustomcolorsCheckBox_3 = uicheckbox(app.PresencevisualizationoptionsTab);
            app.UsecustomcolorsCheckBox_3.Text = 'Use custom colors';
            app.UITable_colors_3 = uitable(app.PresencevisualizationoptionsTab);
            app.UITable_colors_3.ColumnName = {'R'; 'G'; 'B'};
            app.UITable_colors_3.RowName = {};
            app.PresencevisualizationoptionsLabel = uilabel(app.PresencevisualizationoptionsTab);
            app.PresencevisualizationoptionsLabel.Text = 'Presence visualization options:';
            app.SizeparameterDropDownLabel = uilabel(app.PresencevisualizationoptionsTab);
            app.SizeparameterDropDownLabel.Text = 'Size parameter:';
            app.SizeparameterDropDown = uidropdown(app.PresencevisualizationoptionsTab);
            app.SizeparameterDropDown.Items = {'count'};
            app.SizeparameterDropDown.Placeholder = 'Select attribute';
            app.SizeparameterDropDown.Value = 'count';
            app.UsepresencevisualizationoptionsCheckBox = uicheckbox(app.PresencevisualizationoptionsTab);
            app.UsepresencevisualizationoptionsCheckBox.Text = ' Use presence visualization options';
            app.EnvironmentaldataTab = uitab(app.TabGroup);
            app.EnvironmentaldataTab.Title = 'Environmental data';
            app.TabGroup2 = uitabgroup(app.EnvironmentaldataTab);
            app.GriddedcolormapTab = uitab(app.TabGroup2);
            app.GriddedcolormapTab.Title = 'Gridded colormap';
            app.SelectgriddeddataButton = uibutton(app.GriddedcolormapTab, 'push');
            app.SelectgriddeddataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectgriddeddataButtonPushed, true);
            app.SelectgriddeddataButton.Text = 'Select gridded data';
            app.InvertcolormapCheckBox = uicheckbox(app.GriddedcolormapTab);
            app.InvertcolormapCheckBox.Text = 'Invert color map';
            app.CleardataButton_gridded = uibutton(app.GriddedcolormapTab, 'push');
            app.CleardataButton_gridded.ButtonPushedFcn = createCallbackFcn(app, @Clear_gridded_data_ButtonPushed, true);
            app.CleardataButton_gridded.Text = 'Clear data';
            app.ShowcolorbarCheckBox = uicheckbox(app.GriddedcolormapTab);
            app.ShowcolorbarCheckBox.Text = 'Show colorbar';
            app.ShowcolorbarCheckBox.Value = true;
            app.AutomaticcolorbarlimitsCheckBox = uicheckbox(app.GriddedcolormapTab);
            app.AutomaticcolorbarlimitsCheckBox.Text = 'Automatic colorbar limits';
            app.ColormapDropDownLabel = uilabel(app.GriddedcolormapTab);
            app.ColormapDropDownLabel.Text = 'Color map';
            app.ColormapDropDown_gridded = uidropdown(app.GriddedcolormapTab);
            app.ColormapDropDown_gridded.Items = {'green', 'blue', 'diverging', 'jet'};
            app.ColormapDropDown_gridded.Value = 'green';
            app.TimevariableDropDown_gridded = uidropdown(app.GriddedcolormapTab);
            app.TimevariableDropDown_gridded.Items = {};
            app.TimevariableDropDown_gridded.Placeholder = 'Select variable';
            app.TimevariableDropDown_gridded.Value = {};
            app.TimevariableLabel = uilabel(app.GriddedcolormapTab);
            app.TimevariableLabel.Text = 'Time variable:';
            app.LatitudevariableDropDown_gridded = uidropdown(app.GriddedcolormapTab);
            app.LatitudevariableDropDown_gridded.Items = {};
            app.LatitudevariableDropDown_gridded.Placeholder = 'Select variable';
            app.LatitudevariableDropDown_gridded.Value = {};
            app.LatitudevariableLabel = uilabel(app.GriddedcolormapTab);
            app.LatitudevariableLabel.Text = 'Latitude variable:';
            app.LongitudevariableDropDown_gridded = uidropdown(app.GriddedcolormapTab);
            app.LongitudevariableDropDown_gridded.Items = {};
            app.LongitudevariableDropDown_gridded.Placeholder = 'Select variable';
            app.LongitudevariableDropDown_gridded.Value = {};
            app.LongitudevariableDropDownLabel = uilabel(app.GriddedcolormapTab);
            app.LongitudevariableDropDownLabel.Text = 'Longitude variable:';
            app.FilepathTextArea_2Label = uilabel(app.GriddedcolormapTab);
            app.FilepathTextArea_2Label.Text = 'File path';
            app.FilepathTextArea_gridded = uitextarea(app.GriddedcolormapTab);
            app.FilepathTextArea_gridded.Editable = 'off';
            app.FilepathTextArea_gridded.Enable = 'off';
            app.ColorbarminEditFieldLabel = uilabel(app.GriddedcolormapTab);
            app.ColorbarminEditFieldLabel.Text = 'Colorbar min';
            app.ColorbarminEditField = uieditfield(app.GriddedcolormapTab, 'numeric');
            app.VariabletoanimateDropDownLabel = uilabel(app.GriddedcolormapTab);
            app.VariabletoanimateDropDownLabel.Text = 'Variable to animate:';
            app.VariabletoanimateDropDown_gridded = uidropdown(app.GriddedcolormapTab);
            app.VariabletoanimateDropDown_gridded.Items = {};
            app.VariabletoanimateDropDown_gridded.Placeholder = 'Select variable';
            app.VariabletoanimateDropDown_gridded.Value = {};
            app.ColorbarmaxEditFieldLabel = uilabel(app.GriddedcolormapTab);
            app.ColorbarmaxEditFieldLabel.Text = 'Colorbar max';
            app.ColorbarmaxEditField = uieditfield(app.GriddedcolormapTab, 'numeric');
            app.ColorbarmaxEditField.Value = 1;
            app.DatasetdimensionsLabel = uilabel(app.GriddedcolormapTab);
            app.DatasetdimensionsLabel.Text = 'Dataset dimensions:';
            app.ColoroptionsLabel = uilabel(app.GriddedcolormapTab);
            app.ColoroptionsLabel.Text = 'Color options:';
            app.GriddeddataLabel = uilabel(app.GriddedcolormapTab);
            app.GriddeddataLabel.Text = 'Gridded data:';
            app.ContoursTab = uitab(app.TabGroup2);
            app.ContoursTab.Title = 'Contours';
            app.ContourdataLabel = uilabel(app.ContoursTab);
            app.ContourdataLabel.Text = 'Contour data:';
            app.SelectcontourdataButton = uibutton(app.ContoursTab, 'push');
            app.SelectcontourdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcontourdataButtonPushed, true);
            app.SelectcontourdataButton.Text = 'Select contour data';
            app.FilepathTextArea_contour = uitextarea(app.ContoursTab);
            app.FilepathTextArea_contour.Editable = 'off';
            app.FilepathTextArea_contour.Enable = 'off';
            app.VariabletoanimateDropDown_contour = uidropdown(app.ContoursTab);
            app.VariabletoanimateDropDown_contour.Items = {};
            app.VariabletoanimateDropDown_contour.Placeholder = 'Select variable';
            app.VariabletoanimateDropDown_contour.Value = {};
            app.TimevariableDropDown_contour = uidropdown(app.ContoursTab);
            app.TimevariableDropDown_contour.Items = {};
            app.TimevariableDropDown_contour.Placeholder = 'Select variable';
            app.TimevariableDropDown_contour.Value = {};
            app.LatitudevariableDropDown_contour = uidropdown(app.ContoursTab);
            app.LatitudevariableDropDown_contour.Items = {};
            app.LatitudevariableDropDown_contour.Placeholder = 'Select variable';
            app.LatitudevariableDropDown_contour.Value = {};
            app.LongitudevariableDropDown_contour = uidropdown(app.ContoursTab);
            app.LongitudevariableDropDown_contour.Items = {};
            app.LongitudevariableDropDown_contour.Placeholder = 'Select variable';
            app.LongitudevariableDropDown_contour.Value = {};
            app.ShowcontourtextCheckBox = uicheckbox(app.ContoursTab);
            app.ShowcontourtextCheckBox.Text = 'Show contour text';
            app.VariabletoanimateDropDownLabel_2 = uilabel(app.ContoursTab);
            app.VariabletoanimateDropDownLabel_2.Text = 'Variable to animate:';
            app.TimevariableLabel_2 = uilabel(app.ContoursTab);
            app.TimevariableLabel_2.Text = 'Time variable:';
            app.LatitudevariableLabel_2 = uilabel(app.ContoursTab);
            app.LatitudevariableLabel_2.Text = 'Latitude variable:';
            app.LongitudevariableDropDownLabel_2 = uilabel(app.ContoursTab);
            app.LongitudevariableDropDownLabel_2.Text = 'Longitude variable:';
            app.CleardataButton_contour = uibutton(app.ContoursTab, 'push');
            app.CleardataButton_contour.ButtonPushedFcn = createCallbackFcn(app, @Clear_contour_data_ButtonPushed, true);
            app.CleardataButton_contour.Text = 'Clear data';
            app.FilepathTextArea_2Label_6 = uilabel(app.ContoursTab);
            app.FilepathTextArea_2Label_6.Text = 'File path';
            app.ContourlinecolorDropDownLabel = uilabel(app.ContoursTab);
            app.ContourlinecolorDropDownLabel.Text = 'Contour line color';
            app.ContourlinecolorDropDown = uidropdown(app.ContoursTab);
            app.ContourlinecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.ContourlinecolorDropDown.Editable = 'on';
            app.ContourlinecolorDropDown.Value = 'black';
            app.ContourlinewidthSpinnerLabel = uilabel(app.ContoursTab);
            app.ContourlinewidthSpinnerLabel.Text = 'Contour line width';
            app.ContourlinewidthSpinner = uispinner(app.ContoursTab);
            app.ContourlinewidthSpinner.Step = 0.1;
            app.ContourlinewidthSpinner.Limits = [0.2 3];
            app.ContourlinewidthSpinner.Value = 0.7;
            app.DatasetdimensionsLabel_2 = uilabel(app.ContoursTab);
            app.DatasetdimensionsLabel_2.Text = 'Dataset dimensions:';
            app.ContouroptionsLabel = uilabel(app.ContoursTab);
            app.ContouroptionsLabel.Text = 'Contour options:';
            app.VectorfieldsTab = uitab(app.TabGroup2);
            app.VectorfieldsTab.Title = 'Vector fields';
            app.SelectvectorfielddataButton = uibutton(app.VectorfieldsTab, 'push');
            app.SelectvectorfielddataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectvectorfielddataButtonPushed, true);
            app.SelectvectorfielddataButton.Text = 'Select vector field data';
            app.CleardataButton_vectorfield = uibutton(app.VectorfieldsTab, 'push');
            app.CleardataButton_vectorfield.ButtonPushedFcn = createCallbackFcn(app, @CleardataButton_vectorfieldPushed, true);
            app.CleardataButton_vectorfield.Text = 'Clear data';
            app.VectorfielddatawindcurrentsLabel = uilabel(app.VectorfieldsTab);
            app.VectorfielddatawindcurrentsLabel.Text = 'Vector field data (wind, currents):';
            app.VvectorLabel = uilabel(app.VectorfieldsTab);
            app.VvectorLabel.Text = 'V vector';
            app.V_vector_DropDown = uidropdown(app.VectorfieldsTab);
            app.V_vector_DropDown.Items = {};
            app.V_vector_DropDown.Placeholder = 'Select variable';
            app.V_vector_DropDown.Value = {};
            app.FilepathTextArea_2Label_7 = uilabel(app.VectorfieldsTab);
            app.FilepathTextArea_2Label_7.Text = 'File path';
            app.FilepathTextArea_vectorfield = uitextarea(app.VectorfieldsTab);
            app.FilepathTextArea_vectorfield.Editable = 'off';
            app.FilepathTextArea_vectorfield.Enable = 'off';
            app.TimevariableLabel_3 = uilabel(app.VectorfieldsTab);
            app.TimevariableLabel_3.Text = 'Time variable:';
            app.TimevariableDropDown_vectorfield = uidropdown(app.VectorfieldsTab);
            app.TimevariableDropDown_vectorfield.Items = {};
            app.TimevariableDropDown_vectorfield.Placeholder = 'Select variable';
            app.TimevariableDropDown_vectorfield.Value = {};
            app.LatitudevariableLabel_3 = uilabel(app.VectorfieldsTab);
            app.LatitudevariableLabel_3.Text = 'Latitude variable:';
            app.LatitudevariableDropDown_vectorfield = uidropdown(app.VectorfieldsTab);
            app.LatitudevariableDropDown_vectorfield.Items = {};
            app.LatitudevariableDropDown_vectorfield.Placeholder = 'Select variable';
            app.LatitudevariableDropDown_vectorfield.Value = {};
            app.LongitudevariableDropDownLabel_3 = uilabel(app.VectorfieldsTab);
            app.LongitudevariableDropDownLabel_3.Text = 'Longitude variable:';
            app.LongitudevariableDropDown_vectorfield = uidropdown(app.VectorfieldsTab);
            app.LongitudevariableDropDown_vectorfield.Items = {};
            app.LongitudevariableDropDown_vectorfield.Placeholder = 'Select variable';
            app.LongitudevariableDropDown_vectorfield.Value = {};
            app.UvectorLabel = uilabel(app.VectorfieldsTab);
            app.UvectorLabel.Text = 'U vector';
            app.U_vector_DropDown = uidropdown(app.VectorfieldsTab);
            app.U_vector_DropDown.Items = {};
            app.U_vector_DropDown.Placeholder = 'Select variable';
            app.U_vector_DropDown.Value = {};
            app.DatasetdimensionsLabel_3 = uilabel(app.VectorfieldsTab);
            app.DatasetdimensionsLabel_3.Text = 'Dataset dimensions:';
            app.VectoroptionsLabel = uilabel(app.VectorfieldsTab);
            app.VectoroptionsLabel.Text = 'Vector options:';
            app.ContourlinecolorDropDown_2Label_2 = uilabel(app.VectorfieldsTab);
            app.ContourlinecolorDropDown_2Label_2.Text = 'Quiver color';
            app.QuivercolorDropDown = uidropdown(app.VectorfieldsTab);
            app.QuivercolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.QuivercolorDropDown.Editable = 'on';
            app.QuivercolorDropDown.Value = 'black';
            app.ContourlinewidthSpinner_2Label_2 = uilabel(app.VectorfieldsTab);
            app.ContourlinewidthSpinner_2Label_2.Visible = 'off';
            app.ContourlinewidthSpinner_2Label_2.Text = 'Quiver size';
            app.QuiversizeSpinner = uispinner(app.VectorfieldsTab);
            app.QuiversizeSpinner.Step = 0.1;
            app.QuiversizeSpinner.Limits = [0.01 3];
            app.QuiversizeSpinner.Visible = 'off';
            app.QuiversizeSpinner.Value = 0.5;
            app.QuiverspeedSpinnerLabel = uilabel(app.VectorfieldsTab);
            app.QuiverspeedSpinnerLabel.Visible = 'off';
            app.QuiverspeedSpinnerLabel.Text = 'Quiver speed';
            app.QuiverspeedSpinner = uispinner(app.VectorfieldsTab);
            app.QuiverspeedSpinner.Limits = [0.5 10];
            app.QuiverspeedSpinner.Visible = 'off';
            app.QuiverspeedSpinner.Value = 1;
            app.AutoscaleCheckBox = uicheckbox(app.VectorfieldsTab);
            app.AutoscaleCheckBox.Visible = 'off';
            app.AutoscaleCheckBox.Text = 'Auto-scale ';
            app.StaticNetCDFTab = uitab(app.TabGroup2);
            app.StaticNetCDFTab.Title = 'Static NetCDF';
            app.SelectstaticdataButton = uibutton(app.StaticNetCDFTab, 'push');
            app.SelectstaticdataButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseStaticNcButtonPushed, true);
            app.SelectstaticdataButton.Text = 'Select static data';
            app.StaticInvertCheckBox = uicheckbox(app.StaticNetCDFTab);
            app.StaticInvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @StaticInvertCheckBoxValueChanged, true);
            app.StaticInvertCheckBox.Text = 'Invert color map';
            app.ClearStaticDataButton = uibutton(app.StaticNetCDFTab, 'push');
            app.ClearStaticDataButton.ButtonPushedFcn = createCallbackFcn(app, @StaticNcClearDataButtonPushed, true);
            app.ClearStaticDataButton.Text = 'Clear data';
            app.StaticShowColorbarCheckBox = uicheckbox(app.StaticNetCDFTab);
            app.StaticShowColorbarCheckBox.ValueChangedFcn = createCallbackFcn(app, @StaticShowColorbarCheckBoxValueChanged, true);
            app.StaticShowColorbarCheckBox.Text = 'Show colorbar';
            app.StaticShowColorbarCheckBox.Value = true;
            app.AutomaticcolorbarlimitsStaticNcCheckBox = uicheckbox(app.StaticNetCDFTab);
            app.AutomaticcolorbarlimitsStaticNcCheckBox.Text = 'Automatic colorbar limits';
            app.ColormapDropDownLabel_2 = uilabel(app.StaticNetCDFTab);
            app.ColormapDropDownLabel_2.Text = 'Color map';
            app.StaticCmapDropDown = uidropdown(app.StaticNetCDFTab);
            app.StaticCmapDropDown.Items = {'green', 'blue', 'diverging', 'jet'};
            app.StaticCmapDropDown.ValueChangedFcn = createCallbackFcn(app, @StaticCmapDropDownValueChanged, true);
            app.StaticCmapDropDown.Value = 'green';
            app.FilepathTextArea_2Label_8 = uilabel(app.StaticNetCDFTab);
            app.FilepathTextArea_2Label_8.Text = 'File path';
            app.StaticNcDataPathTextArea = uitextarea(app.StaticNetCDFTab);
            app.StaticNcDataPathTextArea.Editable = 'off';
            app.StaticNcDataPathTextArea.Enable = 'off';
            app.ColorbarminEditField_2Label = uilabel(app.StaticNetCDFTab);
            app.ColorbarminEditField_2Label.Text = 'Colorbar min';
            app.StaticCLimMinField = uieditfield(app.StaticNetCDFTab, 'numeric');
            app.StaticCLimMinField.ValueChangedFcn = createCallbackFcn(app, @StaticCLimMinFieldValueChanged, true);
            app.ColorbarmaxEditField_2Label = uilabel(app.StaticNetCDFTab);
            app.ColorbarmaxEditField_2Label.Text = 'Colorbar max';
            app.StaticCLimMaxField = uieditfield(app.StaticNetCDFTab, 'numeric');
            app.StaticCLimMaxField.ValueChangedFcn = createCallbackFcn(app, @StaticCLimMaxFieldValueChanged, true);
            app.StaticCLimMaxField.Value = 1;
            app.DatasetStaticdimensionsLabel_4 = uilabel(app.StaticNetCDFTab);
            app.DatasetStaticdimensionsLabel_4.Text = 'Dataset dimensions:';
            app.ColoroptionsLabel_2 = uilabel(app.StaticNetCDFTab);
            app.ColoroptionsLabel_2.Text = 'Color options:';
            app.GriddeddataLabel_2 = uilabel(app.StaticNetCDFTab);
            app.GriddeddataLabel_2.Text = 'Gridded data:';
            app.StaticLatVarDropDown = uidropdown(app.StaticNetCDFTab);
            app.StaticLatVarDropDown.Items = {};
            app.StaticLatVarDropDown.ValueChangedFcn = createCallbackFcn(app, @StaticLatVarDropDownValueChanged, true);
            app.StaticLatVarDropDown.Placeholder = 'Select variable';
            app.StaticLatVarDropDown.Value = {};
            app.LatitudevariableLabel_5 = uilabel(app.StaticNetCDFTab);
            app.LatitudevariableLabel_5.Text = 'Latitude variable:';
            app.StaticLonVarDropDown = uidropdown(app.StaticNetCDFTab);
            app.StaticLonVarDropDown.Items = {};
            app.StaticLonVarDropDown.ValueChangedFcn = createCallbackFcn(app, @StaticLonVarDropDownValueChanged, true);
            app.StaticLonVarDropDown.Placeholder = 'Select variable';
            app.StaticLonVarDropDown.Value = {};
            app.LongitudevariableDropDownLabel_5 = uilabel(app.StaticNetCDFTab);
            app.LongitudevariableDropDownLabel_5.Text = 'Longitude variable:';
            app.StaticTimeDropDown = uidropdown(app.StaticNetCDFTab);
            app.StaticTimeDropDown.Items = {'No time variable', 'Time slice'};
            app.StaticTimeDropDown.ValueChangedFcn = createCallbackFcn(app, @StaticTimeDropDownValueChanged, true);
            app.StaticTimeDropDown.Placeholder = 'Select variable';
            app.StaticTimeDropDown.Value = 'No time variable';
            app.TimevariableLabel_5 = uilabel(app.StaticNetCDFTab);
            app.TimevariableLabel_5.Text = 'Time variable:';
            app.TimevariableLabel_6 = uilabel(app.StaticNetCDFTab);
            app.TimevariableLabel_6.Text = 'Time slice';
            app.TimeSliceDropDown = uidropdown(app.StaticNetCDFTab);
            app.TimeSliceDropDown.Items = {'time'};
            app.TimeSliceDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeSliceDropDownValueChanged, true);
            app.TimeSliceDropDown.Placeholder = 'Select variable';
            app.TimeSliceDropDown.Value = 'time';
            app.VariabletoanimateDropDownLabel_3 = uilabel(app.StaticNetCDFTab);
            app.VariabletoanimateDropDownLabel_3.Text = 'Variable to animate:';
            app.StaticVarDropDown = uidropdown(app.StaticNetCDFTab);
            app.StaticVarDropDown.Items = {};
            app.StaticVarDropDown.ValueChangedFcn = createCallbackFcn(app, @StaticVarDropDownValueChanged, true);
            app.StaticVarDropDown.Placeholder = 'Select variable';
            app.StaticVarDropDown.Value = {};
            app.StaticGeoTIFFTab = uitab(app.TabGroup2);
            app.StaticGeoTIFFTab.Title = 'Static GeoTIFF';
            app.SelectgriddeddataButton_3 = uibutton(app.StaticGeoTIFFTab, 'push');
            app.SelectgriddeddataButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectgriddeddataButton_3Pushed, true);
            app.SelectgriddeddataButton_3.Text = 'Select gridded data';
            app.InvertcolormapCheckBox_3 = uicheckbox(app.StaticGeoTIFFTab);
            app.InvertcolormapCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @InvertcolormapCheckBox_3ValueChanged, true);
            app.InvertcolormapCheckBox_3.Text = 'Invert color map';
            app.CleardataButton_gridded_3 = uibutton(app.StaticGeoTIFFTab, 'push');
            app.CleardataButton_gridded_3.ButtonPushedFcn = createCallbackFcn(app, @CleardaStaticTiffClearDataButtonPushedtaButton_gridded_3Pushed, true);
            app.CleardataButton_gridded_3.Text = 'Clear data';
            app.ShowcolorbarCheckBox_3 = uicheckbox(app.StaticGeoTIFFTab);
            app.ShowcolorbarCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @ShowcolorbarCheckBox_3ValueChanged, true);
            app.ShowcolorbarCheckBox_3.Text = 'Show colorbar';
            app.ShowcolorbarCheckBox_3.Value = true;
            app.AutomaticcolorbarlimitsCheckBox_3 = uicheckbox(app.StaticGeoTIFFTab);
            app.AutomaticcolorbarlimitsCheckBox_3.Text = 'Automatic colorbar limits';
            app.ColormapDropDownLabel_3 = uilabel(app.StaticGeoTIFFTab);
            app.ColormapDropDownLabel_3.Text = 'Color map';
            app.ColormapDropDown_gridded_3 = uidropdown(app.StaticGeoTIFFTab);
            app.ColormapDropDown_gridded_3.Items = {'green', 'blue', 'diverging', 'jet'};
            app.ColormapDropDown_gridded_3.Value = 'green';
            app.FilepathTextArea_2Label_9 = uilabel(app.StaticGeoTIFFTab);
            app.FilepathTextArea_2Label_9.Text = 'File path';
            app.FilepathTextArea_gridded_3 = uitextarea(app.StaticGeoTIFFTab);
            app.FilepathTextArea_gridded_3.Editable = 'off';
            app.FilepathTextArea_gridded_3.Enable = 'off';
            app.ColorbarminEditField_3Label = uilabel(app.StaticGeoTIFFTab);
            app.ColorbarminEditField_3Label.Text = 'Colorbar min';
            app.ColorbarminEditField_3 = uieditfield(app.StaticGeoTIFFTab, 'numeric');
            app.ColorbarmaxEditField_3Label = uilabel(app.StaticGeoTIFFTab);
            app.ColorbarmaxEditField_3Label.Text = 'Colorbar max';
            app.ColorbarmaxEditField_3 = uieditfield(app.StaticGeoTIFFTab, 'numeric');
            app.ColorbarmaxEditField_3.Value = 1;
            app.DatasetdimensionsLabel_5 = uilabel(app.StaticGeoTIFFTab);
            app.DatasetdimensionsLabel_5.Text = 'Dataset dimensions:';
            app.ColoroptionsLabel_3 = uilabel(app.StaticGeoTIFFTab);
            app.ColoroptionsLabel_3.Text = 'Color options:';
            app.GriddeddataLabel_3 = uilabel(app.StaticGeoTIFFTab);
            app.GriddeddataLabel_3.Text = 'Gridded data:';
            app.ShapefilesTab = uitab(app.TabGroup);
            app.ShapefilesTab.Title = 'Shapefiles';
            app.SelectshapefileButton = uibutton(app.ShapefilesTab, 'push');
            app.SelectshapefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectshapefileButtonPushed, true);
            app.SelectshapefileButton.Text = 'Select shapefile';
            app.FilepathTextArea_shapefile = uitextarea(app.ShapefilesTab);
            app.FilepathTextArea_shapefile.Editable = 'off';
            app.FilepathTextArea_shapefile.Enable = 'off';
            app.FilepathTextArea_2Label_2 = uilabel(app.ShapefilesTab);
            app.FilepathTextArea_2Label_2.Text = 'File path';
            app.LinecolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.LinecolorDropDownLabel.Enable = 'off';
            app.LinecolorDropDownLabel.Text = 'Line color';
            app.LinecolorDropDown = uidropdown(app.ShapefilesTab);
            app.LinecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.LinecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @LinecolorDropDownValueChanged, true);
            app.LinecolorDropDown.Enable = 'off';
            app.LinecolorDropDown.Value = 'black';
            app.LinewidthSpinnerLabel = uilabel(app.ShapefilesTab);
            app.LinewidthSpinnerLabel.Enable = 'off';
            app.LinewidthSpinnerLabel.Text = 'Line width';
            app.LinewidthSpinner = uispinner(app.ShapefilesTab);
            app.LinewidthSpinner.Step = 0.1;
            app.LinewidthSpinner.Limits = [0.2 3];
            app.LinewidthSpinner.ValueChangedFcn = createCallbackFcn(app, @LinewidthSpinnerValueChanged, true);
            app.LinewidthSpinner.Editable = 'off';
            app.LinewidthSpinner.Enable = 'off';
            app.LinewidthSpinner.Value = 0.7;
            app.FacecolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.FacecolorDropDownLabel.Enable = 'off';
            app.FacecolorDropDownLabel.Text = 'Face color';
            app.FacecolorDropDown = uidropdown(app.ShapefilesTab);
            app.FacecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.FacecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @FacecolorDropDownValueChanged, true);
            app.FacecolorDropDown.Enable = 'off';
            app.FacecolorDropDown.Value = 'black';
            app.EdgecolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.EdgecolorDropDownLabel.Enable = 'off';
            app.EdgecolorDropDownLabel.Text = 'Edge color';
            app.EdgecolorDropDown = uidropdown(app.ShapefilesTab);
            app.EdgecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.EdgecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgecolorDropDownValueChanged, true);
            app.EdgecolorDropDown.Enable = 'off';
            app.EdgecolorDropDown.Value = 'black';
            app.FaceopacitySpinnerLabel = uilabel(app.ShapefilesTab);
            app.FaceopacitySpinnerLabel.Enable = 'off';
            app.FaceopacitySpinnerLabel.Text = 'Face opacity';
            app.FaceopacitySpinner = uispinner(app.ShapefilesTab);
            app.FaceopacitySpinner.Step = 0.1;
            app.FaceopacitySpinner.Limits = [0 1];
            app.FaceopacitySpinner.ValueChangedFcn = createCallbackFcn(app, @FaceopacitySpinnerValueChanged, true);
            app.FaceopacitySpinner.Editable = 'off';
            app.FaceopacitySpinner.Enable = 'off';
            app.FaceopacitySpinner.Value = 1;
            app.UITable_shapefiles = uitable(app.ShapefilesTab);
            app.UITable_shapefiles.ColumnName = {'File'; 'Geometry'; 'Point/Line/Edge color'; 'Marker style'; 'Marker size'; 'Line width'; 'Face color'; 'Face opacity'};
            app.UITable_shapefiles.RowName = {};
            app.ClearallshapefilesButton = uibutton(app.ShapefilesTab, 'push');
            app.ClearallshapefilesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallshapefilesButtonPushed, true);
            app.ClearallshapefilesButton.Text = 'Clear all shapefiles';
            app.ClearlastshapefileButton = uibutton(app.ShapefilesTab, 'push');
            app.ClearlastshapefileButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlastshapefileButtonPushed, true);
            app.ClearlastshapefileButton.Text = 'Clear last shapefile';
            app.LineoptionsLabel = uilabel(app.ShapefilesTab);
            app.LineoptionsLabel.Text = 'Line options:';
            app.PointoptionsLabel = uilabel(app.ShapefilesTab);
            app.PointoptionsLabel.Text = 'Point options:';
            app.PolygonoptionsLabel = uilabel(app.ShapefilesTab);
            app.PolygonoptionsLabel.Text = 'Polygon options:';
            app.PointcolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.PointcolorDropDownLabel.Enable = 'off';
            app.PointcolorDropDownLabel.Text = 'Point color';
            app.PointcolorDropDown_shapefile = uidropdown(app.ShapefilesTab);
            app.PointcolorDropDown_shapefile.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.PointcolorDropDown_shapefile.ValueChangedFcn = createCallbackFcn(app, @PointcolorDropDown_shapefileValueChanged, true);
            app.PointcolorDropDown_shapefile.Enable = 'off';
            app.PointcolorDropDown_shapefile.Value = 'black';
            app.MarkerstyleDropDown_2Label = uilabel(app.ShapefilesTab);
            app.MarkerstyleDropDown_2Label.Enable = 'off';
            app.MarkerstyleDropDown_2Label.Text = 'Marker style';
            app.MarkerstyleDropDown_shapefile = uidropdown(app.ShapefilesTab);
            app.MarkerstyleDropDown_shapefile.Items = {'hexagram', 'pentagram', 'square', 'diamond ', 'circle'};
            app.MarkerstyleDropDown_shapefile.ItemsData = {'hexagram', 'pentagram', 'square', 'diamond ', 'o'};
            app.MarkerstyleDropDown_shapefile.ValueChangedFcn = createCallbackFcn(app, @MarkerstyleDropDown_shapefileValueChanged, true);
            app.MarkerstyleDropDown_shapefile.Enable = 'off';
            app.MarkerstyleDropDown_shapefile.Value = 'hexagram';
            app.MarkersizeSpinnerLabel_2 = uilabel(app.ShapefilesTab);
            app.MarkersizeSpinnerLabel_2.Enable = 'off';
            app.MarkersizeSpinnerLabel_2.Text = 'Marker size';
            app.MarkersizeSpinner_shapefile = uispinner(app.ShapefilesTab);
            app.MarkersizeSpinner_shapefile.Limits = [0.2 100];
            app.MarkersizeSpinner_shapefile.ValueChangedFcn = createCallbackFcn(app, @MarkersizeSpinner_shapefileValueChanged, true);
            app.MarkersizeSpinner_shapefile.Editable = 'off';
            app.MarkersizeSpinner_shapefile.Enable = 'off';
            app.MarkersizeSpinner_shapefile.Value = 1;
            app.LabeledpointsTab = uitab(app.TabGroup);
            app.LabeledpointsTab.Title = 'Labeled points';
            app.Labeledpoints = uilabel(app.LabeledpointsTab);
            app.Labeledpoints.Text = 'Labeled points';
            app.SelectfileButton_labeledPoints = uibutton(app.LabeledpointsTab, 'push');
            app.SelectfileButton_labeledPoints.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButton_labeledPointsPushed, true);
            app.SelectfileButton_labeledPoints.Text = 'Select file';
            app.FilepathTextArea_labeledPoints = uitextarea(app.LabeledpointsTab);
            app.FilepathTextArea_labeledPoints.Editable = 'off';
            app.FilepathTextArea_labeledPoints.Enable = 'off';
            app.FilepathTextAreaLabel_labeledPoints = uilabel(app.LabeledpointsTab);
            app.FilepathTextAreaLabel_labeledPoints.Text = 'File path';
            app.MarkercolorDropDownLabel = uilabel(app.LabeledpointsTab);
            app.MarkercolorDropDownLabel.Text = 'Marker color';
            app.MarkercolorDropDown_labeledPoints = uidropdown(app.LabeledpointsTab);
            app.MarkercolorDropDown_labeledPoints.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.MarkercolorDropDown_labeledPoints.Editable = 'on';
            app.MarkercolorDropDown_labeledPoints.Value = 'red';
            app.MarkersizeSpinnerLabel = uilabel(app.LabeledpointsTab);
            app.MarkersizeSpinnerLabel.Text = 'Marker size';
            app.MarkersizeSpinner_labeledPoints = uispinner(app.LabeledpointsTab);
            app.MarkersizeSpinner_labeledPoints.Limits = [1 100];
            app.MarkersizeSpinner_labeledPoints.Editable = 'off';
            app.MarkersizeSpinner_labeledPoints.Value = 20;
            app.CleardataButton_labeledPoints = uibutton(app.LabeledpointsTab, 'push');
            app.CleardataButton_labeledPoints.ButtonPushedFcn = createCallbackFcn(app, @CleardataButton_labeledPointsPushed, true);
            app.CleardataButton_labeledPoints.Text = 'Clear data';
            app.ShowlabeltextCheckBox = uicheckbox(app.LabeledpointsTab);
            app.ShowlabeltextCheckBox.Text = 'Show label text';
            app.ShowlabeltextCheckBox.Value = true;
            app.LabelcolorDropDownLabel = uilabel(app.LabeledpointsTab);
            app.LabelcolorDropDownLabel.Text = 'Label color';
            app.LabelcolorDropDown_labeledPoints = uidropdown(app.LabeledpointsTab);
            app.LabelcolorDropDown_labeledPoints.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.LabelcolorDropDown_labeledPoints.Editable = 'on';
            app.LabelcolorDropDown_labeledPoints.Value = 'black';
            app.LabelsizeSpinnerLabel = uilabel(app.LabeledpointsTab);
            app.LabelsizeSpinnerLabel.Text = 'Label size';
            app.LabelsizeSpinner_labeledPoints = uispinner(app.LabeledpointsTab);
            app.LabelsizeSpinner_labeledPoints.Limits = [1 100];
            app.LabelsizeSpinner_labeledPoints.Editable = 'off';
            app.LabelsizeSpinner_labeledPoints.Value = 8;
            app.ColorpointsbyattributeCheckBox = uicheckbox(app.LabeledpointsTab);
            app.ColorpointsbyattributeCheckBox.Text = 'Color points by attribute';
            app.ColorpointsbyDropDownLabel = uilabel(app.LabeledpointsTab);
            app.ColorpointsbyDropDownLabel.Text = 'Color points by:';
            app.ColorpointsbyDropDown = uidropdown(app.LabeledpointsTab);
            app.ColorpointsbyDropDown.Items = {};
            app.ColorpointsbyDropDown.Placeholder = 'Select attribute';
            app.ColorpointsbyDropDown.Value = {};
            app.UsecustomcolorsCheckBox_labeledPoints = uicheckbox(app.LabeledpointsTab);
            app.UsecustomcolorsCheckBox_labeledPoints.Text = 'Use custom colors';
            app.AddcolorButton_labeledPoints = uibutton(app.LabeledpointsTab, 'push');
            app.AddcolorButton_labeledPoints.ButtonPushedFcn = createCallbackFcn(app, @AddcolorButtonPushed, true);
            app.AddcolorButton_labeledPoints.Text = 'Add color';
            app.ClearcolorsButton_labeledPoints = uibutton(app.LabeledpointsTab, 'push');
            app.ClearcolorsButton_labeledPoints.ButtonPushedFcn = createCallbackFcn(app, @ClearcolorsButtonPushed, true);
            app.ClearcolorsButton_labeledPoints.Text = 'Clear colors';
            app.UITable_colors_labeledPoints = uitable(app.LabeledpointsTab);
            app.UITable_colors_labeledPoints.ColumnName = {'R'; 'G'; 'B'};
            app.UITable_colors_labeledPoints.RowName = {};
            app.ElevationTab = uitab(app.TabGroup);
            app.ElevationTab.Title = 'Elevation';
            app.ShowcontourtextCheckBox_elevation = uicheckbox(app.ElevationTab);
            app.ShowcontourtextCheckBox_elevation.Text = 'Show contour text';
            app.ContourlinewidthSpinner_2Label = uilabel(app.ElevationTab);
            app.ContourlinewidthSpinner_2Label.Text = 'Contour line width';
            app.ContourlinewidthSpinner_elevation = uispinner(app.ElevationTab);
            app.ContourlinewidthSpinner_elevation.Step = 0.1;
            app.ContourlinewidthSpinner_elevation.Limits = [0.2 3];
            app.ContourlinewidthSpinner_elevation.Value = 0.7;
            app.CheckBox = uicheckbox(app.ElevationTab);
            app.IncludeelevationcontoursCheckBox = uicheckbox(app.ElevationTab);
            app.IncludeelevationcontoursCheckBox.Text = 'Include elevation contours';
            app.NumberoflevelsSpinnerLabel = uilabel(app.ElevationTab);
            app.NumberoflevelsSpinnerLabel.Text = 'Number of levels';
            app.NumberoflevelsSpinner = uispinner(app.ElevationTab);
            app.NumberoflevelsSpinner.Limits = [2 30];
            app.NumberoflevelsSpinner.Value = 10;
            app.ContourlinecolorDropDown_2Label = uilabel(app.ElevationTab);
            app.ContourlinecolorDropDown_2Label.Text = 'Contour line color';
            app.ContourlinecolorDropDown_elevation = uidropdown(app.ElevationTab);
            app.ContourlinecolorDropDown_elevation.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.ContourlinecolorDropDown_elevation.Editable = 'on';
            app.ContourlinecolorDropDown_elevation.Value = 'black';
            app.OutputPanel = uipanel(app.ECODATAAnimateUIFigure);
            app.OutputPanel.Title = 'Output:';
            app.SetoutputdirectoryButton = uibutton(app.OutputPanel, 'push');
            app.SetoutputdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetoutputdirectoryButtonPushed, true);
            app.SetoutputdirectoryButton.Text = 'Set output directory';
            app.FilepathTextArea_3Label = uilabel(app.OutputPanel);
            app.FilepathTextArea_3Label.Text = 'File path';
            app.FilepathTextArea_output = uitextarea(app.OutputPanel);
            app.FilepathTextArea_output.Editable = 'off';
            app.FilepathTextArea_output.Enable = 'off';
            app.GeneratelastframeonlyCheckBox = uicheckbox(app.OutputPanel);
            app.GeneratelastframeonlyCheckBox.Text = 'Generate last frame only';
            app.GeneratelastframeonlyCheckBox.Value = true;
            app.LoadsettingsButton = uibutton(app.ECODATAAnimateUIFigure, 'push');
            app.LoadsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsettingsButtonPushed, true);
            app.LoadsettingsButton.Text = 'Load settings';
            % Show the figure after all components are created
            app.ECODATAAnimateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = animator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECODATAAnimateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECODATAAnimateUIFigure)
        end
    end
end