classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OutputnameEditField       matlab.ui.control.EditField
        OutputnameEditFieldLabel  matlab.ui.control.Label
        LoadanimationButton       matlab.ui.control.Button
        individual_local_identifierDropDown  matlab.ui.control.DropDown
        individual_local_identifierDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        data % input dataset
        filtered_data % subset of the input dataset, filtered by user selections
        var %variable in the dataset to annotate 
        individual_id % the individual to plot 
        fileout %output file path
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            root = fileparts(fileparts(mfilename('fullpath')));
            functions_path = char(fullfile(root, "functions"));
            data_path = char(fullfile(root, "data/example_datasets"));
            addpath(functions_path)
            addpath(data_path)

            %load the data
            app.data = read_downloaded_data('snow_caribou.csv');
            %set variables and output 
            app.var = 'MODISSnow500m8dTerraSnowCover';
            app.fileout = 'output.avi';
        end

        % Drop down opening function: individual_local_identifierDropDown
        function individual_local_identifierDropDownOpening(app, event)
            app.individual_local_identifierDropDown.Items = unique(app.data.individual_local_identifier);
        end

        % Value changed function: individual_local_identifierDropDown
        function individual_local_identifierDropDownValueChanged(app, event)
            app.individual_id = app.individual_local_identifierDropDown.Value;
            app.filtered_data = select_individual(app.data, app.individual_id);
        end

        % Button pushed function: LoadanimationButton
        function LoadanimationButtonPushed(app, event)
            animate_track_annotated(app.filtered_data, app.var, app.fileout)
        end

        % Value changed function: OutputnameEditField
        function OutputnameEditFieldValueChanged(app, event)
            app.fileout = char(app.OutputnameEditField.Value);
            
            
        end

        % Value changing function: OutputnameEditField
        function OutputnameEditFieldValueChanging(app, event)
            app.fileout = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.individual_local_identifierDropDownLabel = uilabel(app.UIFigure);
            app.individual_local_identifierDropDownLabel.Text = 'individual_local_identifier';
            app.individual_local_identifierDropDown = uidropdown(app.UIFigure);
            app.individual_local_identifierDropDown.Items = {'Select identifier', ''};
            app.individual_local_identifierDropDown.DropDownOpeningFcn = createCallbackFcn(app, @individual_local_identifierDropDownOpening, true);
            app.individual_local_identifierDropDown.ValueChangedFcn = createCallbackFcn(app, @individual_local_identifierDropDownValueChanged, true);
            app.individual_local_identifierDropDown.Value = 'Select identifier';
            app.LoadanimationButton = uibutton(app.UIFigure, 'push');
            app.LoadanimationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadanimationButtonPushed, true);
            app.LoadanimationButton.Text = 'Load animation';
            app.OutputnameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputnameEditFieldLabel.Text = 'Output name';
            app.OutputnameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputnameEditField.ValueChangedFcn = createCallbackFcn(app, @OutputnameEditFieldValueChanged, true);
            app.OutputnameEditField.ValueChangingFcn = createCallbackFcn(app, @OutputnameEditFieldValueChanging, true);
            app.OutputnameEditField.Placeholder = 'output.avi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end