classdef gridded_data_animation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OutputanimationfilePanel        matlab.ui.container.Panel
        FilepathTextArea_3              matlab.ui.control.TextArea
        FilepathTextArea_3Label         matlab.ui.control.Label
        SetoutputfileButton             matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AnimaltrackdataTab              matlab.ui.container.Tab
        VisualizationoptionsPanel       matlab.ui.container.Panel
        FramerateEditField              matlab.ui.control.NumericEditField
        FramerateEditFieldLabel         matlab.ui.control.Label
        TrackmemoryEditField            matlab.ui.control.NumericEditField
        TrackmemoryEditFieldLabel       matlab.ui.control.Label
        DatafiltersPanel                matlab.ui.container.Panel
        NumberofpointsinselectionTextArea  matlab.ui.control.TextArea
        NumberofpointsinselectionTextAreaLabel  matlab.ui.control.Label
        LatesttimestampinselectionTextArea  matlab.ui.control.TextArea
        LatesttimestampinselectionTextAreaLabel  matlab.ui.control.Label
        EarliesttimestampinselectionTextArea  matlab.ui.control.TextArea
        EarliesttimestampinselectionTextAreaLabel  matlab.ui.control.Label
        NumberofindividualsselectedTextArea  matlab.ui.control.TextArea
        NumberofindividualsselectedTextAreaLabel  matlab.ui.control.Label
        MaxlongitudeEditField           matlab.ui.control.NumericEditField
        MaxlongitudeEditFieldLabel      matlab.ui.control.Label
        MinlongitudeEditField           matlab.ui.control.NumericEditField
        MinlongitudeEditFieldLabel      matlab.ui.control.Label
        MaxlatitudeEditField            matlab.ui.control.NumericEditField
        MaxlatitudeEditFieldLabel       matlab.ui.control.Label
        MinlatitudeEditField            matlab.ui.control.NumericEditField
        MinlatitudeEditFieldLabel       matlab.ui.control.Label
        EnddateDatePicker               matlab.ui.control.DatePicker
        EnddateDatePickerLabel          matlab.ui.control.Label
        StartdateDatePicker             matlab.ui.control.DatePicker
        StartdateDatePickerLabel        matlab.ui.control.Label
        SelectionsummaryLabel           matlab.ui.control.Label
        UpdatefiltersButton             matlab.ui.control.Button
        GeographicextentLabel           matlab.ui.control.Label
        TimerangeLabel                  matlab.ui.control.Label
        IndividualsLabel                matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        AllindividualsNode              matlab.ui.container.TreeNode
        AnimaltracksLabel               matlab.ui.control.Label
        FilepathTextArea                matlab.ui.control.TextArea
        FilepathTextAreaLabel           matlab.ui.control.Label
        SelecttrackdataButton           matlab.ui.control.Button
        EnvironmentaldataTab            matlab.ui.container.Tab
        LongitudevariableDropDownLabel_2  matlab.ui.control.Label
        LatitudevariableLabel_2         matlab.ui.control.Label
        TimevariableLabel_2             matlab.ui.control.Label
        VariabletoanimateDropDownLabel_2  matlab.ui.control.Label
        ContourlinewidthSpinner         matlab.ui.control.Spinner
        ContourlinewidthSpinnerLabel    matlab.ui.control.Label
        ShowcontourtextCheckBox         matlab.ui.control.CheckBox
        ContourlinecolorDropDown        matlab.ui.control.DropDown
        ContourlinecolorDropDownLabel   matlab.ui.control.Label
        LongitudevariableDropDown_2     matlab.ui.control.DropDown
        LatitudevariableDropDown_2      matlab.ui.control.DropDown
        TimevariableDropDown_2          matlab.ui.control.DropDown
        VariabletoanimateDropDown_2     matlab.ui.control.DropDown
        FilepathTextArea_4              matlab.ui.control.TextArea
        SelectcontourdataButton         matlab.ui.control.Button
        ContourdataLabel                matlab.ui.control.Label
        InvertcolormapCheckBox          matlab.ui.control.CheckBox
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        LongitudevariableDropDown       matlab.ui.control.DropDown
        LongitudevariableDropDownLabel  matlab.ui.control.Label
        LatitudevariableDropDown        matlab.ui.control.DropDown
        LatitudevariableLabel           matlab.ui.control.Label
        TimevariableDropDown            matlab.ui.control.DropDown
        TimevariableLabel               matlab.ui.control.Label
        VariabletoanimateDropDown       matlab.ui.control.DropDown
        VariabletoanimateDropDownLabel  matlab.ui.control.Label
        FilepathTextArea_2              matlab.ui.control.TextArea
        FilepathTextArea_2Label         matlab.ui.control.Label
        SelectgriddeddataButton         matlab.ui.control.Button
        GriddedenvironmentaldataLabel   matlab.ui.control.Label
        ShapefilesTab                   matlab.ui.container.Tab
        FaceAlphaSpinner                matlab.ui.control.Spinner
        FaceAlphaSpinnerLabel           matlab.ui.control.Label
        EdgecolorDropDown               matlab.ui.control.DropDown
        EdgecolorDropDownLabel          matlab.ui.control.Label
        FacecolorDropDown               matlab.ui.control.DropDown
        FacecolorDropDownLabel          matlab.ui.control.Label
        LinewidthSpinner                matlab.ui.control.Spinner
        LinewidthSpinnerLabel           matlab.ui.control.Label
        LinecolorDropDown               matlab.ui.control.DropDown
        LinecolorDropDownLabel          matlab.ui.control.Label
        FilepathTextArea_2Label_2       matlab.ui.control.Label
        FilepathTextArea_5              matlab.ui.control.TextArea
        SelectshapefileButton           matlab.ui.control.Button
        ShapefilesLabel                 matlab.ui.control.Label
        SelectdatafilesLabel            matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        CreateanimationButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        root
        data_path
        input_path
        input_datafile  % file with input track data
        input_fullpath
        gridded_data
        contour_data
        shapefile_stack
        raster_image
        labeled_points
        gridded_datafile
        gridded_path
        gridded_fullpath
        gridded_varnames
        gridded_var_label
        gridded_time_label
        gridded_lat_label
        gridded_long_label
        latmin
        latmax
        lonmin
        lonmax
        start_time
        end_time
        data % input dataset
        filtered_data % subset of the input dataset, filtered by user selections
        individual_id % the individual to plot 
        output_dir
        selected_individuals
        track_memory
        frame_rate
        cmap
    end
    
    methods (Access = private)
        
        function apply_track_filters(app)
            % Select time window 
            app.filtered_data = app.data((app.data.timestamp >= app.start_time) & (app.data.timestamp <= app.end_time), :);

            % Select extent 
            app.filtered_data = app.filtered_data((app.filtered_data.location_long>=app.lonmin) & ...
                (app.filtered_data.location_long <=app.lonmax) & ...
                (app.filtered_data.location_lat>=app.latmin) & (app.filtered_data.location_lat <= app.latmax),:);

            % Select individuals 
            app.filtered_data = select_individuals(app.filtered_data, app.selected_individuals);
            
        end
        
        
        function autoselect_valid_varnames(app)
            potential_timevars = {'time', 'timestamp'};
            potential_latvars = {'lat', 'latitude'};
            potential_lonvars = {'lon', 'long', 'longitude'};

            matched_vars = {};

            matched_timevar = intersect(app.gridded_varnames, potential_timevars);
            if length(matched_timevar) >= 1
                app.TimevariableDropDown.Value = matched_timevar{1};
                app.TimevariableDropDownValueChanged()
                matched_vars(1+length(matched_vars)) = matched_timevar(1);
            end

            matched_latvar = intersect(app.gridded_varnames, potential_latvars);
            if length(matched_latvar) >= 1
                app.LatitudevariableDropDown.Value = matched_latvar{1};
                app.LatitudevariableDropDownValueChanged()
                matched_vars(1+length(matched_vars)) = matched_latvar(1);

            end

            matched_lonvar = intersect(app.gridded_varnames, potential_lonvars);
            if length(matched_lonvar) >= 1
                app.LongitudevariableDropDown.Value = matched_lonvar{1};
                app.LongitudevariableDropDownValueChanged()
                matched_vars(1+length(matched_vars)) = matched_lonvar(1);

            end

            % Update the var of interest to the 1st var not matching time,
            % lat, or lon
            other_vars = app.gridded_varnames(~ismember(app.gridded_varnames,matched_vars));
            if length(matched_timevar) >= 1
                app.VariabletoanimateDropDown.Value= other_vars{1};
                app.VariabletoanimateDropDownValueChanged()
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.root = fileparts(fileparts(mfilename('fullpath')));
            functions_path = char(fullfile(app.root, "functions"));
            app.data_path = char(fullfile(app.root, "data/example_datasets"));
            addpath(functions_path)
            addpath(app.data_path)

            % Initialize inputs for the animation function 
            app.gridded_data = containers.Map();
            app.contour_data = containers.Map();
            app.shapefile_stack = {};
            app.raster_image = containers.Map();
            app.labeled_points = containers.Map();

        end

        % Button pushed function: SelecttrackdataButton
        function SelecttrackdataButtonPushed(app, event)
            
            % Open file browser to select input data 
            [app.input_datafile, app.input_path] = uigetfile('*.csv', 'select', app.data_path);
            app.input_fullpath = fullfile(app.input_path, app.input_datafile);
            app.FilepathTextArea.Value = app.input_fullpath;
            
            %load the data
            app.data = read_downloaded_data(app.input_fullpath);

            %update the geolimits 
            [latlim, lonlim] = get_geolimits(app.data, .10);
            app.latmin = latlim(1);
            app.latmax = latlim(2);
            app.lonmin = lonlim(1);
            app.lonmax = lonlim(2);

            app.MinlatitudeEditField.Value = app.latmin;
            app.MaxlatitudeEditField.Value = app.latmax;
            app.MinlongitudeEditField.Value = app.lonmin;
            app.MaxlongitudeEditField.Value = app.lonmax;

            % set default start and end times 
            app.start_time = min(app.data.timestamp);
            app.end_time = max(app.data.timestamp);
            app.StartdateDatePicker.Value = app.start_time;
            app.EnddateDatePicker.Value = app.end_time;


            % Update the list box with the available individuals 
            individuals = unique(app.data.individual_local_identifier);
%             app.ListBox.Items = unique(app.data.individual_local_identifier);

            % Update tree 
            for i =1:length(individuals)
                uitreenode(app.AllindividualsNode, "Text", individuals{i});
            end
            expand(app.Tree);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
%             app.selected_individuals = {app.Tree.CheckedNodes.Text};
%             app.filtered_data = select_individuals(app.data, app.selected_individuals);
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            app.selected_individuals = {checkedNodes.Text};
            
        end

        % Button pushed function: SelectgriddeddataButton
        function SelectgriddeddataButtonPushed(app, event)
            % Open file browser to select input data 
            [app.gridded_datafile, app.gridded_path] = uigetfile('*.nc', 'select', app.data_path);
            app.gridded_data('filename') = fullfile(app.gridded_path, app.gridded_datafile);
            app.FilepathTextArea_2.Value = app.gridded_data('filename');
            
            % Get variable names 
            app.gridded_varnames= {ncinfo(app.gridded_data('filename')).Variables.Name};
            app.VariabletoanimateDropDown.Items = app.gridded_varnames;
            app.TimevariableDropDown.Items = app.gridded_varnames; 
            app.LatitudevariableDropDown.Items = app.gridded_varnames; 
            app.LongitudevariableDropDown.Items = app.gridded_varnames;
            
            % Auto-select time, lat, and long
            app.autoselect_valid_varnames()

 
        end

        % Value changed function: TimevariableDropDown
        function TimevariableDropDownValueChanged(app, event)
            app.gridded_time_label= app.TimevariableDropDown.Value;
%             nc_time = ncread(app.gridded_data('filename'), app.gridded_time_label);
%             nctimestamp = datetime(datevec(double(nc_time + datenum('2000-01-01 00:00:00'))));
% 
%             % set default start and end times 
%             app.start_time = min(nctimestamp);
%             app.end_time = max(nctimestamp);
%             app.StartdateDatePicker.Value = app.start_time;
%             app.EnddateDatePicker.Value = app.end_time;
            
        end

        % Value changed function: LatitudevariableDropDown
        function LatitudevariableDropDownValueChanged(app, event)
            app.gridded_lat_label = app.LatitudevariableDropDown.Value;
            
        end

        % Value changed function: LongitudevariableDropDown
        function LongitudevariableDropDownValueChanged(app, event)
            app.gridded_long_label = app.LongitudevariableDropDown.Value;
            
        end

        % Value changed function: VariabletoanimateDropDown
        function VariabletoanimateDropDownValueChanged(app, event)
            app.gridded_var_label = app.VariabletoanimateDropDown.Value;
            
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            app.gridded_data('cmap') = value;
            
        end

        % Value changed function: InvertcolormapCheckBox
        function InvertcolormapCheckBoxValueChanged(app, event)
            value = app.InvertcolormapCheckBox.Value;
            app.gridded_data('invert_cmap') = value;
        end

        % Button pushed function: CreateanimationButton
        function CreateanimationButtonPushed(app, event)
            app.StatusTextArea.Value = "Generating animation... Please be patient";

            % Gather gridded data info 
            app.gridded_data('latvar') = app.LatitudevariableDropDown.Value;
            app.gridded_data('lonvar') = app.LongitudevariableDropDown.Value;
            app.gridded_data('timevar') = app.TimevariableDropDown.Value;
            app.gridded_data('var_of_interest') = app.VariabletoanimateDropDown.Value;
            app.gridded_data('cmap') = app.ColormapDropDown.Value;
            app.gridded_data('invert_cmap') = app.InvertcolormapCheckBox.Value;

            % Gather contour data info 
            app.contour_data('latvar') = app.LatitudevariableDropDown_2.Value;
            app.contour_data('lonvar') = app.LongitudevariableDropDown_2.Value; 
            app.contour_data('timevar') = app.TimevariableDropDown_2.Value;
            app.contour_data('var_of_interest') = app.VariabletoanimateDropDown_2.Value;
            app.contour_data('LineColor') = app.ContourlinecolorDropDown.Value;
            app.contour_data('LineWidth') = app.ContourlinewidthSpinner.Value;
            if app.ShowcontourtextCheckBox.Value; app.contour_data('ShowText') = 'on'; 
            elseif ~app.ShowcontourtextCheckBox.Value; app.contour_data('ShowText') = 'off'; end

            animate_gridded_ndvi(app.filtered_data, gridded_data = app.gridded_data, ...
                contour_data = app.contour_data, ...
                shapefile_stack = app.shapefile_stack, ...
                start_time=app.start_time, ...
                end_time=app.end_time, track_memory=app.TrackmemoryEditField.Value, ...
                output_directory=app.output_dir, ...
                latmin=app.MinlatitudeEditField.Value, latmax = app.MaxlatitudeEditField.Value, ...
                lonmin=app.MinlongitudeEditField.Value, lonmax=app.MaxlongitudeEditField.Value)

            app.StatusTextArea.Value = "Animation saved to the output directory.";
        end

        % Button pushed function: SetoutputfileButton
        function SetoutputfileButtonPushed(app, event)
            app.output_dir = uigetdir();
            app.FilepathTextArea_3.Value = app.output_dir; 
        end

        % Value changed function: StartdateDatePicker
        function StartdateDatePickerValueChanged(app, event)
            app.start_time = app.StartdateDatePicker.Value;
            
        end

        % Value changed function: EnddateDatePicker
        function EnddateDatePickerValueChanged(app, event)
            app.end_time = app.EnddateDatePicker.Value;
            
        end

        % Value changed function: MinlatitudeEditField
        function MinlatitudeEditFieldValueChanged(app, event)
            app.latmin = app.MinlatitudeEditField.Value;
            
        end

        % Value changed function: MaxlatitudeEditField
        function MaxlatitudeEditFieldValueChanged(app, event)
            app.latmax = app.MaxlatitudeEditField.Value;
            
        end

        % Value changed function: MinlongitudeEditField
        function MinlongitudeEditFieldValueChanged(app, event)
            app.lonmin = app.MinlongitudeEditField.Value;
            
        end

        % Value changed function: MaxlongitudeEditField
        function MaxlongitudeEditFieldValueChanged(app, event)
            app.lonmax = app.MaxlongitudeEditField.Value;
            
        end

        % Value changed function: TrackmemoryEditField
        function TrackmemoryEditFieldValueChanged(app, event)
            app.track_memory = app.TrackmemoryEditField.Value;
            
        end

        % Value changed function: FramerateEditField
        function FramerateEditFieldValueChanged(app, event)
            app.frame_rate = app.FramerateEditField.Value;
            
        end

        % Button pushed function: UpdatefiltersButton
        function UpdatefiltersButtonPushed(app, event)
            app.apply_track_filters()
            app.NumberofpointsinselectionTextArea.Value = num2str(height(app.filtered_data));
            app.NumberofindividualsselectedTextArea.Value = num2str(length(unique(app.filtered_data.individual_local_identifier)));
            app.EarliesttimestampinselectionTextArea.Value = datestr(min(app.filtered_data.timestamp));
            app.LatesttimestampinselectionTextArea.Value = datestr(max(app.filtered_data.timestamp));
        end

        % Button pushed function: SelectcontourdataButton
        function SelectcontourdataButtonPushed(app, event)
            [datafile, path] = uigetfile('*.nc', 'select', app.data_path);
            app.contour_data('filename') = fullfile(path, datafile);
            app.FilepathTextArea_4.Value = app.contour_data('filename');
            
            % Get variable names 
            varnames= {ncinfo(app.contour_data('filename')).Variables.Name};
            app.VariabletoanimateDropDown_2.Items = varnames;
            app.TimevariableDropDown_2.Items = varnames; 
            app.LatitudevariableDropDown_2.Items = varnames; 
            app.LongitudevariableDropDown_2.Items = varnames;
            
            % Auto-select time, lat, and long
%             app.autoselect_valid_varnames()
        end

        % Button pushed function: SelectshapefileButton
        function SelectshapefileButtonPushed(app, event)
            shapefile = containers.Map();
            [datafile, path] = uigetfile('*.shp', 'select', app.data_path);
            shapefile('filename') = fullfile(path, datafile);
            app.FilepathTextArea_5.Value = shapefile('filename'); 
            % Get geometry of shapefile 
            geom = shaperead(shapefile('filename'), 'RecordNumbers', 1).Geometry;

            if strcmp(geom, 'Line')
                app.LinecolorDropDown.Editable=true;
                app.LinecolorDropDown.Enable=true;
                app.LinewidthSpinner.Editable=true;
                app.LinewidthSpinner.Enable=true;
                app.EdgecolorDropDown.Editable = false;
                app.EdgecolorDropDown.Enable = false;
                app.FacecolorDropDown.Editable = false;
                app.FacecolorDropDown.Enable = false; 
                app.FaceAlphaSpinner.Editable = false;
                app.FaceAlphaSpinner.Enable = false; 
                shapefile('LineColor') = app.LinecolorDropDown.Value;
                shapefile('LineWidth') = app.LinewidthSpinner.Value;
            elseif strcmp(geom, 'Polygon')
                app.EdgecolorDropDown.Editable = true;
                app.EdgecolorDropDown.Enable = true;
                app.FacecolorDropDown.Editable = true;
                app.FacecolorDropDown.Enable = true; 
                app.FaceAlphaSpinner.Editable = true;
                app.FaceAlphaSpinner.Enable = true; 
                app.LinecolorDropDown.Editable=false;
                app.LinecolorDropDown.Enable=false;
                app.LinewidthSpinner.Editable=false;
                app.LinewidthSpinner.Enable=false;
                shapefile('EdgeColor') = app.EdgecolorDropDown.Value;
                shapefile('FaceColor') = app.FacecolorDropDown.Value; 
                shapefile('FaceAlpha') = app.FaceAlphaSpinner.Value; 
            end
            app.shapefile_stack{end+1} = shapefile;
        end

        % Value changed function: LinecolorDropDown
        function LinecolorDropDownValueChanged(app, event)
            app.shapefile_stack{1}('LineColor') = app.LinecolorDropDown.Value;
            
        end

        % Value changed function: LinewidthSpinner
        function LinewidthSpinnerValueChanged(app, event)
            app.shapefile_stack{1}('LineWidth') = app.LinewidthSpinner.Value;
            
        end

        % Value changed function: EdgecolorDropDown
        function EdgecolorDropDownValueChanged(app, event)
            app.shapefile_stack{1}('EdgeColor') = app.EdgecolorDropDown.Value;
            
        end

        % Value changed function: FacecolorDropDown
        function FacecolorDropDownValueChanged(app, event)
            app.shapefile_stack{1}('FaceColor') = app.FacecolorDropDown.Value;
            
        end

        % Value changed function: FaceAlphaSpinner
        function FaceAlphaSpinnerValueChanged(app, event)
            app.shapefile_stack{1}('FaceAlpha') = app.FaceAlphaSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CreateanimationButton = uibutton(app.UIFigure, 'push');
            app.CreateanimationButton.ButtonPushedFcn = createCallbackFcn(app, @CreateanimationButtonPushed, true);
            app.CreateanimationButton.Text = 'Create animation';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.SelectdatafilesLabel = uilabel(app.UIFigure);
            app.SelectdatafilesLabel.Text = 'Select data files:';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnimaltrackdataTab = uitab(app.TabGroup);
            app.AnimaltrackdataTab.Title = 'Animal track data';
            app.SelecttrackdataButton = uibutton(app.AnimaltrackdataTab, 'push');
            app.SelecttrackdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttrackdataButtonPushed, true);
            app.SelecttrackdataButton.Text = 'Select track data';
            app.FilepathTextAreaLabel = uilabel(app.AnimaltrackdataTab);
            app.FilepathTextAreaLabel.Text = 'File path';
            app.FilepathTextArea = uitextarea(app.AnimaltrackdataTab);
            app.FilepathTextArea.Editable = 'off';
            app.FilepathTextArea.Enable = 'off';
            app.AnimaltracksLabel = uilabel(app.AnimaltrackdataTab);
            app.AnimaltracksLabel.Text = 'Animal tracks:';
            app.DatafiltersPanel = uipanel(app.AnimaltrackdataTab);
            app.DatafiltersPanel.Title = 'Data filters:';
            app.Tree = uitree(app.DatafiltersPanel, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.AllindividualsNode = uitreenode(app.Tree);
            app.AllindividualsNode.Text = 'All individuals';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.IndividualsLabel = uilabel(app.DatafiltersPanel);
            app.IndividualsLabel.Text = 'Individuals: ';
            app.TimerangeLabel = uilabel(app.DatafiltersPanel);
            app.TimerangeLabel.Text = 'Time range: ';
            app.GeographicextentLabel = uilabel(app.DatafiltersPanel);
            app.GeographicextentLabel.Text = 'Geographic extent: ';
            app.UpdatefiltersButton = uibutton(app.DatafiltersPanel, 'push');
            app.UpdatefiltersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatefiltersButtonPushed, true);
            app.UpdatefiltersButton.Text = 'Update filters';
            app.SelectionsummaryLabel = uilabel(app.DatafiltersPanel);
            app.SelectionsummaryLabel.Text = 'Selection summary: ';
            app.StartdateDatePickerLabel = uilabel(app.DatafiltersPanel);
            app.StartdateDatePickerLabel.Text = 'Start date';
            app.StartdateDatePicker = uidatepicker(app.DatafiltersPanel);
            app.StartdateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartdateDatePickerValueChanged, true);
            app.EnddateDatePickerLabel = uilabel(app.DatafiltersPanel);
            app.EnddateDatePickerLabel.Text = 'End date';
            app.EnddateDatePicker = uidatepicker(app.DatafiltersPanel);
            app.EnddateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EnddateDatePickerValueChanged, true);
            app.MinlatitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MinlatitudeEditFieldLabel.Text = 'Min latitude';
            app.MinlatitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MinlatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MinlatitudeEditFieldValueChanged, true);
            app.MaxlatitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MaxlatitudeEditFieldLabel.Text = 'Max latitude';
            app.MaxlatitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MaxlatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlatitudeEditFieldValueChanged, true);
            app.MinlongitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MinlongitudeEditFieldLabel.Text = 'Min longitude';
            app.MinlongitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MinlongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MinlongitudeEditFieldValueChanged, true);
            app.MaxlongitudeEditFieldLabel = uilabel(app.DatafiltersPanel);
            app.MaxlongitudeEditFieldLabel.Text = 'Max longitude';
            app.MaxlongitudeEditField = uieditfield(app.DatafiltersPanel, 'numeric');
            app.MaxlongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlongitudeEditFieldValueChanged, true);
            app.NumberofindividualsselectedTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.NumberofindividualsselectedTextAreaLabel.Text = 'Number of individuals selected:';
            app.NumberofindividualsselectedTextArea = uitextarea(app.DatafiltersPanel);
            app.EarliesttimestampinselectionTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.EarliesttimestampinselectionTextAreaLabel.Text = 'Earliest timestamp in selection:';
            app.EarliesttimestampinselectionTextArea = uitextarea(app.DatafiltersPanel);
            app.LatesttimestampinselectionTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.LatesttimestampinselectionTextAreaLabel.Text = 'Latest timestamp in selection:';
            app.LatesttimestampinselectionTextArea = uitextarea(app.DatafiltersPanel);
            app.NumberofpointsinselectionTextAreaLabel = uilabel(app.DatafiltersPanel);
            app.NumberofpointsinselectionTextAreaLabel.Text = 'Number of points in selection:';
            app.NumberofpointsinselectionTextArea = uitextarea(app.DatafiltersPanel);
            app.VisualizationoptionsPanel = uipanel(app.AnimaltrackdataTab);
            app.VisualizationoptionsPanel.Title = 'Visualization options:';
            app.TrackmemoryEditFieldLabel = uilabel(app.VisualizationoptionsPanel);
            app.TrackmemoryEditFieldLabel.Text = 'Track memory';
            app.TrackmemoryEditField = uieditfield(app.VisualizationoptionsPanel, 'numeric');
            app.TrackmemoryEditField.Limits = [1 Inf];
            app.TrackmemoryEditField.ValueChangedFcn = createCallbackFcn(app, @TrackmemoryEditFieldValueChanged, true);
            app.TrackmemoryEditField.Value = 20;
            app.FramerateEditFieldLabel = uilabel(app.VisualizationoptionsPanel);
            app.FramerateEditFieldLabel.Text = 'Frame rate';
            app.FramerateEditField = uieditfield(app.VisualizationoptionsPanel, 'numeric');
            app.FramerateEditField.Limits = [0 Inf];
            app.FramerateEditField.ValueChangedFcn = createCallbackFcn(app, @FramerateEditFieldValueChanged, true);
            app.FramerateEditField.Value = 14;
            app.EnvironmentaldataTab = uitab(app.TabGroup);
            app.EnvironmentaldataTab.Title = 'Environmental data';
            app.GriddedenvironmentaldataLabel = uilabel(app.EnvironmentaldataTab);
            app.GriddedenvironmentaldataLabel.Text = 'Gridded environmental data:';
            app.SelectgriddeddataButton = uibutton(app.EnvironmentaldataTab, 'push');
            app.SelectgriddeddataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectgriddeddataButtonPushed, true);
            app.SelectgriddeddataButton.Text = 'Select gridded data';
            app.FilepathTextArea_2Label = uilabel(app.EnvironmentaldataTab);
            app.FilepathTextArea_2Label.Text = 'File path';
            app.FilepathTextArea_2 = uitextarea(app.EnvironmentaldataTab);
            app.FilepathTextArea_2.Editable = 'off';
            app.FilepathTextArea_2.Enable = 'off';
            app.VariabletoanimateDropDownLabel = uilabel(app.EnvironmentaldataTab);
            app.VariabletoanimateDropDownLabel.Text = 'Variable to animate:';
            app.VariabletoanimateDropDown = uidropdown(app.EnvironmentaldataTab);
            app.VariabletoanimateDropDown.Items = {};
            app.VariabletoanimateDropDown.ValueChangedFcn = createCallbackFcn(app, @VariabletoanimateDropDownValueChanged, true);
            app.VariabletoanimateDropDown.Placeholder = 'Select variable';
            app.VariabletoanimateDropDown.Value = {};
            app.TimevariableLabel = uilabel(app.EnvironmentaldataTab);
            app.TimevariableLabel.Text = 'Time variable:';
            app.TimevariableDropDown = uidropdown(app.EnvironmentaldataTab);
            app.TimevariableDropDown.Items = {};
            app.TimevariableDropDown.ValueChangedFcn = createCallbackFcn(app, @TimevariableDropDownValueChanged, true);
            app.TimevariableDropDown.Placeholder = 'Select variable';
            app.TimevariableDropDown.Value = {};
            app.LatitudevariableLabel = uilabel(app.EnvironmentaldataTab);
            app.LatitudevariableLabel.Text = 'Latitude variable:';
            app.LatitudevariableDropDown = uidropdown(app.EnvironmentaldataTab);
            app.LatitudevariableDropDown.Items = {};
            app.LatitudevariableDropDown.ValueChangedFcn = createCallbackFcn(app, @LatitudevariableDropDownValueChanged, true);
            app.LatitudevariableDropDown.Placeholder = 'Select variable';
            app.LatitudevariableDropDown.Value = {};
            app.LongitudevariableDropDownLabel = uilabel(app.EnvironmentaldataTab);
            app.LongitudevariableDropDownLabel.Text = 'Longitude variable:';
            app.LongitudevariableDropDown = uidropdown(app.EnvironmentaldataTab);
            app.LongitudevariableDropDown.Items = {};
            app.LongitudevariableDropDown.ValueChangedFcn = createCallbackFcn(app, @LongitudevariableDropDownValueChanged, true);
            app.LongitudevariableDropDown.Placeholder = 'Select variable';
            app.LongitudevariableDropDown.Value = {};
            app.ColormapDropDownLabel = uilabel(app.EnvironmentaldataTab);
            app.ColormapDropDownLabel.Text = 'Color map';
            app.ColormapDropDown = uidropdown(app.EnvironmentaldataTab);
            app.ColormapDropDown.Items = {'green', 'blue', 'diverging', 'jet'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'green';
            app.InvertcolormapCheckBox = uicheckbox(app.EnvironmentaldataTab);
            app.InvertcolormapCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertcolormapCheckBoxValueChanged, true);
            app.InvertcolormapCheckBox.Text = 'Invert color map';
            app.ContourdataLabel = uilabel(app.EnvironmentaldataTab);
            app.ContourdataLabel.Text = 'Contour data:';
            app.SelectcontourdataButton = uibutton(app.EnvironmentaldataTab, 'push');
            app.SelectcontourdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcontourdataButtonPushed, true);
            app.SelectcontourdataButton.Text = 'Select contour data';
            app.FilepathTextArea_4 = uitextarea(app.EnvironmentaldataTab);
            app.FilepathTextArea_4.Editable = 'off';
            app.FilepathTextArea_4.Enable = 'off';
            app.VariabletoanimateDropDown_2 = uidropdown(app.EnvironmentaldataTab);
            app.VariabletoanimateDropDown_2.Items = {};
            app.VariabletoanimateDropDown_2.Placeholder = 'Select variable';
            app.VariabletoanimateDropDown_2.Value = {};
            app.TimevariableDropDown_2 = uidropdown(app.EnvironmentaldataTab);
            app.TimevariableDropDown_2.Items = {};
            app.TimevariableDropDown_2.Placeholder = 'Select variable';
            app.TimevariableDropDown_2.Value = {};
            app.LatitudevariableDropDown_2 = uidropdown(app.EnvironmentaldataTab);
            app.LatitudevariableDropDown_2.Items = {};
            app.LatitudevariableDropDown_2.Placeholder = 'Select variable';
            app.LatitudevariableDropDown_2.Value = {};
            app.LongitudevariableDropDown_2 = uidropdown(app.EnvironmentaldataTab);
            app.LongitudevariableDropDown_2.Items = {};
            app.LongitudevariableDropDown_2.Placeholder = 'Select variable';
            app.LongitudevariableDropDown_2.Value = {};
            app.ContourlinecolorDropDownLabel = uilabel(app.EnvironmentaldataTab);
            app.ContourlinecolorDropDownLabel.Text = 'Contour line color';
            app.ContourlinecolorDropDown = uidropdown(app.EnvironmentaldataTab);
            app.ContourlinecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.ContourlinecolorDropDown.Value = 'black';
            app.ShowcontourtextCheckBox = uicheckbox(app.EnvironmentaldataTab);
            app.ShowcontourtextCheckBox.Text = 'Show contour text';
            app.ContourlinewidthSpinnerLabel = uilabel(app.EnvironmentaldataTab);
            app.ContourlinewidthSpinnerLabel.Text = 'Contour line width';
            app.ContourlinewidthSpinner = uispinner(app.EnvironmentaldataTab);
            app.ContourlinewidthSpinner.Step = 0.1;
            app.ContourlinewidthSpinner.Limits = [0.2 3];
            app.ContourlinewidthSpinner.Value = 0.7;
            app.VariabletoanimateDropDownLabel_2 = uilabel(app.EnvironmentaldataTab);
            app.VariabletoanimateDropDownLabel_2.Text = 'Variable to animate:';
            app.TimevariableLabel_2 = uilabel(app.EnvironmentaldataTab);
            app.TimevariableLabel_2.Text = 'Time variable:';
            app.LatitudevariableLabel_2 = uilabel(app.EnvironmentaldataTab);
            app.LatitudevariableLabel_2.Text = 'Latitude variable:';
            app.LongitudevariableDropDownLabel_2 = uilabel(app.EnvironmentaldataTab);
            app.LongitudevariableDropDownLabel_2.Text = 'Longitude variable:';
            app.ShapefilesTab = uitab(app.TabGroup);
            app.ShapefilesTab.Title = 'Shapefiles';
            app.ShapefilesLabel = uilabel(app.ShapefilesTab);
            app.ShapefilesLabel.Text = 'Shapefiles:';
            app.SelectshapefileButton = uibutton(app.ShapefilesTab, 'push');
            app.SelectshapefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectshapefileButtonPushed, true);
            app.SelectshapefileButton.Text = 'Select shapefile';
            app.FilepathTextArea_5 = uitextarea(app.ShapefilesTab);
            app.FilepathTextArea_5.Editable = 'off';
            app.FilepathTextArea_5.Enable = 'off';
            app.FilepathTextArea_2Label_2 = uilabel(app.ShapefilesTab);
            app.FilepathTextArea_2Label_2.Text = 'File path';
            app.LinecolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.LinecolorDropDownLabel.Text = 'Line color';
            app.LinecolorDropDown = uidropdown(app.ShapefilesTab);
            app.LinecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.LinecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @LinecolorDropDownValueChanged, true);
            app.LinecolorDropDown.Value = 'black';
            app.LinewidthSpinnerLabel = uilabel(app.ShapefilesTab);
            app.LinewidthSpinnerLabel.Enable = 'off';
            app.LinewidthSpinnerLabel.Text = 'Line width';
            app.LinewidthSpinner = uispinner(app.ShapefilesTab);
            app.LinewidthSpinner.Step = 0.1;
            app.LinewidthSpinner.Limits = [0.2 3];
            app.LinewidthSpinner.ValueChangedFcn = createCallbackFcn(app, @LinewidthSpinnerValueChanged, true);
            app.LinewidthSpinner.Editable = 'off';
            app.LinewidthSpinner.Enable = 'off';
            app.LinewidthSpinner.Value = 0.7;
            app.FacecolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.FacecolorDropDownLabel.Enable = 'off';
            app.FacecolorDropDownLabel.Text = 'Face color';
            app.FacecolorDropDown = uidropdown(app.ShapefilesTab);
            app.FacecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.FacecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @FacecolorDropDownValueChanged, true);
            app.FacecolorDropDown.Enable = 'off';
            app.FacecolorDropDown.Value = 'black';
            app.EdgecolorDropDownLabel = uilabel(app.ShapefilesTab);
            app.EdgecolorDropDownLabel.Enable = 'off';
            app.EdgecolorDropDownLabel.Text = 'Edge color';
            app.EdgecolorDropDown = uidropdown(app.ShapefilesTab);
            app.EdgecolorDropDown.Items = {'black', 'white', 'yellow', 'red', 'green', 'blue', 'cyan', 'magenta'};
            app.EdgecolorDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgecolorDropDownValueChanged, true);
            app.EdgecolorDropDown.Enable = 'off';
            app.EdgecolorDropDown.Value = 'black';
            app.FaceAlphaSpinnerLabel = uilabel(app.ShapefilesTab);
            app.FaceAlphaSpinnerLabel.Enable = 'off';
            app.FaceAlphaSpinnerLabel.Text = 'Face Alpha';
            app.FaceAlphaSpinner = uispinner(app.ShapefilesTab);
            app.FaceAlphaSpinner.Step = 0.1;
            app.FaceAlphaSpinner.Limits = [0 1];
            app.FaceAlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @FaceAlphaSpinnerValueChanged, true);
            app.FaceAlphaSpinner.Editable = 'off';
            app.FaceAlphaSpinner.Enable = 'off';
            app.FaceAlphaSpinner.Value = 1;
            app.OutputanimationfilePanel = uipanel(app.UIFigure);
            app.OutputanimationfilePanel.Title = 'Output animation file:';
            app.SetoutputfileButton = uibutton(app.OutputanimationfilePanel, 'push');
            app.SetoutputfileButton.ButtonPushedFcn = createCallbackFcn(app, @SetoutputfileButtonPushed, true);
            app.SetoutputfileButton.Text = 'Set output file';
            app.FilepathTextArea_3Label = uilabel(app.OutputanimationfilePanel);
            app.FilepathTextArea_3Label.Text = 'File path';
            app.FilepathTextArea_3 = uitextarea(app.OutputanimationfilePanel);
            app.FilepathTextArea_3.Editable = 'off';
            app.FilepathTextArea_3.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gridded_data_animation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end