classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileselectionLabel          matlab.ui.control.Label
        DataselectionLabel          matlab.ui.control.Label
        StatusTextArea              matlab.ui.control.TextArea
        StatusTextAreaLabel         matlab.ui.control.Label
        OutputfileTextArea          matlab.ui.control.TextArea
        OutputfileTextAreaLabel     matlab.ui.control.Label
        SetoutputfileButton         matlab.ui.control.Button
        InputfileTextArea           matlab.ui.control.TextArea
        InputfileTextAreaLabel      matlab.ui.control.Label
        SelectinputtrackdataButton  matlab.ui.control.Button
        LoadanimationButton         matlab.ui.control.Button
        individual_local_identifierDropDown  matlab.ui.control.DropDown
        individual_local_identifierDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        root
        data_path
        input_path
        input_datafile  % file with input track data
        input_fullpath
        data % input dataset
        filtered_data % subset of the input dataset, filtered by user selections
        var %variable in the dataset to annotate 
        individual_id % the individual to plot 
        output_path
        output_fullpath %output file name
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.root = fileparts(fileparts(mfilename('fullpath')));
            functions_path = char(fullfile(app.root, "functions"));
            app.data_path = char(fullfile(app.root, "data/example_datasets"));
            addpath(functions_path)
            addpath(app.data_path)

        end

        % Drop down opening function: individual_local_identifierDropDown
        function individual_local_identifierDropDownOpening(app, event)
            app.individual_local_identifierDropDown.Items = unique(app.data.individual_local_identifier);
        end

        % Value changed function: individual_local_identifierDropDown
        function individual_local_identifierDropDownValueChanged(app, event)
            app.individual_id = app.individual_local_identifierDropDown.Value;
            app.filtered_data = select_individual(app.data, app.individual_id);
        end

        % Button pushed function: LoadanimationButton
        function LoadanimationButtonPushed(app, event)
            app.StatusTextArea.Value = "Generating animation... Please be patient";
            animate_track_annotated(app.filtered_data, app.var, app.output_fullpath)
            app.StatusTextArea.Value = "Animation saved to the output directory.";
        end

        % Button pushed function: SelectinputtrackdataButton
        function SelectinputtrackdataButtonPushed(app, event)
            
            % Open file browser to select input data 
            [app.input_datafile, app.input_path] = uigetfile('*.csv', 'select', app.data_path);
            app.input_fullpath = fullfile(app.input_path, app.input_datafile);
            app.InputfileTextArea.Value = app.input_fullpath;
            
            %load the data
            app.data = read_downloaded_data(app.input_datafile);
            %set variables to use from input dataset 
            app.var = 'MODISSnow500m8dTerraSnowCover';
        end

        % Button pushed function: SetoutputfileButton
        function SetoutputfileButtonPushed(app, event)
            [output_fname, app.output_path] = uiputfile('output.avi', 'Select', fullfile(app.root, 'output', 'output.avi'));
            app.output_fullpath = fullfile(app.output_path, output_fname);
            app.OutputfileTextArea.Value = app.output_fullpath; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.individual_local_identifierDropDownLabel = uilabel(app.UIFigure);
            app.individual_local_identifierDropDownLabel.Text = 'individual_local_identifier';
            app.individual_local_identifierDropDown = uidropdown(app.UIFigure);
            app.individual_local_identifierDropDown.Items = {'Select identifier', ''};
            app.individual_local_identifierDropDown.DropDownOpeningFcn = createCallbackFcn(app, @individual_local_identifierDropDownOpening, true);
            app.individual_local_identifierDropDown.ValueChangedFcn = createCallbackFcn(app, @individual_local_identifierDropDownValueChanged, true);
            app.individual_local_identifierDropDown.Value = 'Select identifier';
            app.LoadanimationButton = uibutton(app.UIFigure, 'push');
            app.LoadanimationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadanimationButtonPushed, true);
            app.LoadanimationButton.Text = 'Load animation';
            app.SelectinputtrackdataButton = uibutton(app.UIFigure, 'push');
            app.SelectinputtrackdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectinputtrackdataButtonPushed, true);
            app.SelectinputtrackdataButton.Text = 'Select input track data';
            app.InputfileTextAreaLabel = uilabel(app.UIFigure);
            app.InputfileTextAreaLabel.Text = 'Input file';
            app.InputfileTextArea = uitextarea(app.UIFigure);
            app.InputfileTextArea.Editable = 'off';
            app.InputfileTextArea.Enable = 'off';
            app.SetoutputfileButton = uibutton(app.UIFigure, 'push');
            app.SetoutputfileButton.ButtonPushedFcn = createCallbackFcn(app, @SetoutputfileButtonPushed, true);
            app.SetoutputfileButton.Text = 'Set output file';
            app.OutputfileTextAreaLabel = uilabel(app.UIFigure);
            app.OutputfileTextAreaLabel.Text = 'Output file';
            app.OutputfileTextArea = uitextarea(app.UIFigure);
            app.OutputfileTextArea.Editable = 'off';
            app.OutputfileTextArea.Enable = 'off';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.DataselectionLabel = uilabel(app.UIFigure);
            app.DataselectionLabel.Text = 'Data selection: ';
            app.FileselectionLabel = uilabel(app.UIFigure);
            app.FileselectionLabel.Text = 'File selection: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end